classdef Login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel_3                 matlab.ui.container.Panel
        PasswordEditField       matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        UsernameEditField       matlab.ui.control.EditField
        UsernameEditFieldLabel  matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        Panel_2                 matlab.ui.container.Panel
        LoginLabel              matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        CancelButton            matlab.ui.control.Button
        LoginButton             matlab.ui.control.Button
    end

    methods (Static)
        function about()
            % Login App
            %
            % This app provides a user authentication interface for the ULTRASAT 
            % Observation Planner GUI.
            %
            % Features:
            % - Allows users to enter a username and password for authentication.
            % - Displays success or failure messages based on login verification.
            % - Provides options to log in or cancel the operation.
        end
    end

    properties (Access = public)
        MainModule      % Reference to the main application module
        Status          % Status of the login attempt, e.g., 'LoginOk' or empty on failure
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainModule)
            app.MainModule = MainModule;
            AppUtils.center(app);
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            % Handles login attempts based on user input.
            %
            % - Retrieves username and password from input fields.
            % - Calls MainModule's login function to verify credentials.
            % - Displays success or failure messages accordingly.
            % - Resumes UI execution if login is successful.            
            
            app.Status = '';
            UserName = app.UsernameEditField.Value;
            Password = app.PasswordEditField.Value;
            Result = app.MainModule.login(UserName, Password);
            if Result
                app.Status = 'LoginOk';
                AppUtils.msgOk(app, 'Login successful, welcome planner!', 'Success');
                uiresume(app.UIFigure);
            else
                AppUtils.msgError(app, 'Login failed, please try again', 'Failed');
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            uiresume(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.LoginButton = uibutton(app.Panel, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_2 = uipanel(app.UIFigure);
            app.LoginLabel = uilabel(app.Panel_2);
            app.LoginLabel.Text = 'Login';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Welcome to ULTRASAT Observation Planner GUI. '; 'Please login to the system using your user name and password.'};
            app.Panel_3 = uipanel(app.UIFigure);
            app.UsernameEditFieldLabel = uilabel(app.Panel_3);
            app.UsernameEditFieldLabel.Text = 'User name';
            app.UsernameEditField = uieditfield(app.Panel_3, 'text');
            app.UsernameEditField.Value = 'yossi';
            app.PasswordEditFieldLabel = uilabel(app.Panel_3);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.Panel_3, 'text');
            app.PasswordEditField.Value = '123';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Login(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end