classdef NewPlan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlanParametersPanel            matlab.ui.container.Panel
        secondsLabel                   matlab.ui.control.Label
        ExposureEditField              matlab.ui.control.EditField
        ExposureEditFieldLabel         matlab.ui.control.Label
        EpochspervisitEditField        matlab.ui.control.EditField
        EpochspervisitEditFieldLabel   matlab.ui.control.Label
        EndtimeEditField               matlab.ui.control.EditField
        EndtimeEditFieldLabel          matlab.ui.control.Label
        StarttimeEditField             matlab.ui.control.EditField
        StarttimeEditFieldLabel        matlab.ui.control.Label
        TitleEditField                 matlab.ui.control.EditField
        TitleEditFieldLabel            matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        CreateNewObservationPlanLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        CancelButton                   matlab.ui.control.Button
        CreateButton                   matlab.ui.control.Button
        SelectPlanTypeButtonGroup      matlab.ui.container.ButtonGroup
        TOOButton                      matlab.ui.control.RadioButton
        AllSSButton                    matlab.ui.control.RadioButton
        DDTButton                      matlab.ui.control.RadioButton
        LCSButton                      matlab.ui.control.RadioButton
        HCSButton                      matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        MainApp     % Description
        Status      % 'Cancel', 'Save'
        PlanType    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainModule)
            app.MainModule = MainModule;
            AppUtils.center(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Status = 'Cancel';
            uiresume(app.UIFigure);
            %delete(app.UIFigure);
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            %

            app.PlanType = [];                                   
            if app.HCSButton.value
                app.PlanType = 'HCS';
            end
            if app.LCSButton.value
                app.PlanType = 'LCS';                
            end
            if app.DDTButton.value
                app.PlanType = 'DDT';                
            end
            if app.AllSSButton.value
                app.PlanType = 'AllSS';
            end
            if app.TOOButton.value
                app.PlanType = 'TOO';
            end           

            app.Status = 'Create';
            uiresume(app.UIFigure);
            %AppUtils.msgOk('')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectPlanTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectPlanTypeButtonGroup.Title = 'Select Plan Type';
            app.HCSButton = uiradiobutton(app.SelectPlanTypeButtonGroup);
            app.HCSButton.Text = 'HCS';
            app.HCSButton.Value = true;
            app.LCSButton = uiradiobutton(app.SelectPlanTypeButtonGroup);
            app.LCSButton.Text = 'LCS';
            app.DDTButton = uiradiobutton(app.SelectPlanTypeButtonGroup);
            app.DDTButton.Text = 'DDT';
            app.AllSSButton = uiradiobutton(app.SelectPlanTypeButtonGroup);
            app.AllSSButton.Text = 'AllSS';
            app.TOOButton = uiradiobutton(app.SelectPlanTypeButtonGroup);
            app.TOOButton.Text = 'TOO';
            app.Panel = uipanel(app.UIFigure);
            app.CreateButton = uibutton(app.Panel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_2 = uipanel(app.UIFigure);
            app.CreateNewObservationPlanLabel = uilabel(app.Panel_2);
            app.CreateNewObservationPlanLabel.Text = 'Create New Observation Plan';
            app.PlanParametersPanel = uipanel(app.UIFigure);
            app.PlanParametersPanel.Title = 'Plan Parameters';
            app.TitleEditFieldLabel = uilabel(app.PlanParametersPanel);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.PlanParametersPanel, 'text');
            app.StarttimeEditFieldLabel = uilabel(app.PlanParametersPanel);
            app.StarttimeEditFieldLabel.Text = 'Start time';
            app.StarttimeEditField = uieditfield(app.PlanParametersPanel, 'text');
            app.EndtimeEditFieldLabel = uilabel(app.PlanParametersPanel);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.EndtimeEditField = uieditfield(app.PlanParametersPanel, 'text');
            app.EpochspervisitEditFieldLabel = uilabel(app.PlanParametersPanel);
            app.EpochspervisitEditFieldLabel.Text = 'Epochs per visit';
            app.EpochspervisitEditField = uieditfield(app.PlanParametersPanel, 'text');
            app.ExposureEditFieldLabel = uilabel(app.PlanParametersPanel);
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.PlanParametersPanel, 'text');
            app.secondsLabel = uilabel(app.PlanParametersPanel);
            app.secondsLabel.Text = 'seconds';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewPlan(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end