classdef OpenPlan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel_3                   matlab.ui.container.Panel
        UITable                   matlab.ui.control.Table
        Panel_2                   matlab.ui.container.Panel
        OpenObservationPlanLabel  matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        CancelButton              matlab.ui.control.Button
        OpenButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainModule      %
        Status          %
        Pk              %
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainModule)
            app.MainModule = MainModule;

            %Data = [];
            %app.UITable.ColumnEditable = false(1, width(Data));
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
         
            Index = app.UITable.Selection;
            if isempty(Index) || (Index < 1)
                return
            end

            app.Pk = app.UITable(Index).pk;
            app.Status = 'Open';
            uiresume(app.UIFigure);                        
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Status = 'Cancel';
            uiresume(app.UIFigure);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.OpenButton = uibutton(app.Panel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_2 = uipanel(app.UIFigure);
            app.OpenObservationPlanLabel = uilabel(app.Panel_2);
            app.OpenObservationPlanLabel.Text = 'Open Observation Plan';
            app.Panel_3 = uipanel(app.UIFigure);
            app.UITable = uitable(app.Panel_3);
            app.UITable.ColumnName = {'Title'; 'User'; 'Updated'; 'Status'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenPlan(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end