classdef PlannerMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        DuplicateMenu                   matlab.ui.container.Menu
        CloseMenu_2                     matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
        SaveToLocalFileMenu             matlab.ui.container.Menu
        OpenFromLocalFileMenu           matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        ConnectLoginMenu                matlab.ui.container.Menu
        DisconnectLogoutMenu            matlab.ui.container.Menu
        ExitPlannerMenu                 matlab.ui.container.Menu
        TargetsMenu                     matlab.ui.container.Menu
        AddUniqueTargetMenu             matlab.ui.container.Menu
        EditUniqueTargetMenu            matlab.ui.container.Menu
        DeleteUniqueTargetMenu          matlab.ui.container.Menu
        ClearAllUniqueTargetsMenu       matlab.ui.container.Menu
        ViewUniqueTargetsTableMenu      matlab.ui.container.Menu
        SaveUniqueTargetsToFileMenu     matlab.ui.container.Menu
        LoadUniqueTargetsFromFileMenu   matlab.ui.container.Menu
        PlanMenu_2                      matlab.ui.container.Menu
        EditPlanTargetMenu              matlab.ui.container.Menu
        DeletePlanTargetMenu            matlab.ui.container.Menu
        ClearAllPlanTargetsMenu         matlab.ui.container.Menu
        ViewPlanTableMenu               matlab.ui.container.Menu
        Menu_3                          matlab.ui.container.Menu
        ParamsMenu                      matlab.ui.container.Menu
        PlanHistoryMenu                 matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        BuildMenu                       matlab.ui.container.Menu
        ValidateMenu                    matlab.ui.container.Menu
        SubmitMenu                      matlab.ui.container.Menu
        PlotsMenu                       matlab.ui.container.Menu
        ViewSkyMapPlotWindowMenu        matlab.ui.container.Menu
        ViewGraphsPlotWindowMenu        matlab.ui.container.Menu
        ClearPlotsMenu                  matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        RefreshMenu                     matlab.ui.container.Menu
        LogWindowMenu                   matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        SNRCalculatorMenu               matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        ObservationPlannerHelpMenu      matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        PanelTopHeader                  matlab.ui.container.Panel
        LabelTopTime                    matlab.ui.control.Label
        LabelTopUser                    matlab.ui.control.Label
        LabelTopNamespace               matlab.ui.control.Label
        LabelTopStatus                  matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        StatusTab                       matlab.ui.container.Tab
        HelpStatusInfoButton            matlab.ui.control.Button
        SubmitStatusButton              matlab.ui.control.Button
        ValidationStatusButton          matlab.ui.control.Button
        BuildStatusButton               matlab.ui.control.Button
        SubmitShortStatusEditField      matlab.ui.control.EditField
        ValidationShortStatusEditField  matlab.ui.control.EditField
        BuildShortStatusEditField       matlab.ui.control.EditField
        SubmitTimeEditField             matlab.ui.control.EditField
        SubmitEditFieldLabel            matlab.ui.control.Label
        ValidationTimeEditField         matlab.ui.control.EditField
        ValidationEditFieldLabel        matlab.ui.control.Label
        BuildTimeEditField              matlab.ui.control.EditField
        BuildEditFieldLabel             matlab.ui.control.Label
        Panel_8                         matlab.ui.container.Panel
        RefreshApprovedTargetsButton_2  matlab.ui.control.Button
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PlanParamsTab                   matlab.ui.container.Tab
        HelpPlanParamsButton            matlab.ui.control.Button
        EndTimeEditField                matlab.ui.control.EditField
        EndTimeEditFieldLabel           matlab.ui.control.Label
        StartTimeEditField              matlab.ui.control.EditField
        StartTimeEditFieldLabel         matlab.ui.control.Label
        PlanTitleEditField              matlab.ui.control.EditField
        PlanTitleEditFieldLabel         matlab.ui.control.Label
        UserNameEditField               matlab.ui.control.EditField
        UserNameEditFieldLabel          matlab.ui.control.Label
        PlanTypeEditField               matlab.ui.control.EditField
        PlanTypeEditFieldLabel          matlab.ui.control.Label
        PlotGraphsDoubleClickUniqueTargetorPlanrowPanel  matlab.ui.container.Panel
        HelpGraphsPlotWindowButton      matlab.ui.control.Button
        OpenGraphsPlotWindowButton      matlab.ui.control.Button
        OpenCalObjTableButton           matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        CalibrationStarButton           matlab.ui.control.RadioButton
        VisibilityButton                matlab.ui.control.RadioButton
        GraphPlotUniqueTargetDropDown   matlab.ui.control.DropDown
        cooSysLabel_3                   matlab.ui.control.Label
        PlotCalibObjDropDown            matlab.ui.control.DropDown
        cooSysLabel_2                   matlab.ui.control.Label
        AxesGraphsPlot                  matlab.ui.control.UIAxes
        PlotSkyMapCurrentlyshowsgeneralskymapPanel  matlab.ui.container.Panel
        HelpSkyMapPlotWindowButton      matlab.ui.control.Button
        OpenSkyMapPlotWindowButton      matlab.ui.control.Button
        Panel_10                        matlab.ui.container.Panel
        PlotCooSysDropDown              matlab.ui.control.DropDown
        cooSysLabel_4                   matlab.ui.control.Label
        UpdateSkyMapButton              matlab.ui.control.Button
        PlotFlagVisibleCheckBox         matlab.ui.control.CheckBox
        PlotFlagApprovedCheckBox        matlab.ui.control.CheckBox
        PlotFlagCalibrationCheckBox     matlab.ui.control.CheckBox
        PlotFlagExtinctionCheckBox      matlab.ui.control.CheckBox
        PlotFlagPlanCheckBox            matlab.ui.control.CheckBox
        PlotFlagUniqueCheckBox          matlab.ui.control.CheckBox
        AxesSkymapPlot                  matlab.ui.control.UIAxes
        ApprovedTargetsPanel            matlab.ui.container.Panel
        HelpUniqueTargetsWindowButton_3  matlab.ui.control.Button
        ShowApprovedTargetsWindowButton  matlab.ui.control.Button
        Panel_5                         matlab.ui.container.Panel
        RefreshApprovedTargetsButton_3  matlab.ui.control.Button
        RefreshApprovedTargetsButton    matlab.ui.control.Button
        UITableApprovedTargets          matlab.ui.control.Table
        PlanPanel                       matlab.ui.container.Panel
        HelpUniqueTargetsWindowButton_2  matlab.ui.control.Button
        ShowPlanRowsWindowButton        matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        EditPlanTargetButton_6          matlab.ui.control.Button
        EditPlanTargetButton_3          matlab.ui.control.Button
        EditPlanTargetButton_2          matlab.ui.control.Button
        EditPlanTargetButton            matlab.ui.control.Button
        CheckPlanTargetsButton          matlab.ui.control.Button
        BuildButton                     matlab.ui.control.Button
        UITablePlanTargets              matlab.ui.control.Table
        UniqueTargetsPanel              matlab.ui.container.Panel
        HelpUniqueTargetsWindowButton   matlab.ui.control.Button
        ShowUniqueTargetsWindowButton   matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        EditPlanTargetButton_5          matlab.ui.control.Button
        EditPlanTargetButton_4          matlab.ui.control.Button
        LoadUniqueTargetsButton         matlab.ui.control.Button
        EditUniqueTargetButton          matlab.ui.control.Button
        AddUniqueTargetButton           matlab.ui.control.Button
        UITableUniqueTargets            matlab.ui.control.Table
        PanelToolbar                    matlab.ui.container.Panel
        HelpButton                      matlab.ui.control.Button
        GDriveCommentsButton            matlab.ui.control.Button
        RetractButton                   matlab.ui.control.Button
        DuplicateButton                 matlab.ui.control.Button
        ModifiedLabel                   matlab.ui.control.Label
        ConnectionStatusEditField       matlab.ui.control.EditField
        ConnectionStatusEditFieldLabel  matlab.ui.control.Label
        SNRCalcButton                   matlab.ui.control.Button
        LoginButton                     matlab.ui.control.Button
        ParamsButton                    matlab.ui.control.Button
        ValidateButton                  matlab.ui.control.Button
        SubmitButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        OpenButton                      matlab.ui.control.Button
        NewButton                       matlab.ui.control.Button
    end

    
    % =====================================================================
    %                              Properties
    % =====================================================================
    
    properties (Access = public)
        % Data
        MainModule                  %
        LoggerApp                   %
        TimerSec                    % Timer object

        % =================================================================
        %   Forms (AppDesigner Apps in ultrasat.planner.gui) - sorted abc
        % =================================================================        
        
        AboutApp                                % About
        AddUniqueTargetApp                      % Unique Target - Add
        AdjustGroupStartTimeApp                 %
        ApprovedTargetParamsApp                 %
        ApprovedTargetsApp                      %
        BuildStatusApp                          %        
        CalibObjTableApp                        %
        DuplicatePlanApp                        %
        EnterStartTimeApp                       %
        LoadPlanFromFileApp                     % Load file from disk
        LoadUniqueTargetsFromFileApp            % Load from text file        
        LoginApp                                % Login
        NewPlanApp                              % Description
        OpenPlanApp                             % Plan - Open
        PlanHistoryApp                          %                
        PlanParamsApp                           %                
        PlanTargetParamsApp                     %
        PlanTargetsApp                          %
        PleaseWaitDlg                           % Please wait dialog        
        PlotSkyMapApp                           %
        PlotGraphsApp                           %        
        PreferencesApp                          %
        SavePlanToFileApp                       % Save to file
        SaveUniqueTargetsToFileApp              % Save to text file        
        SettingsApp                             % Settings        
        SubmitStatusApp                         %
        UniqueTargetParamsApp                   % Unique Target - Params
        UniqueTargetsApp                        % Unique Targets - Window
        ValidationStatusApp                     % Validation stauts & history
        
        % =================================================================
        % Data
        AllowEdit                               % = ~ReadOnly
        AllowEditMsg = 'Cannot edit plan with status submitted'
        Preferences                             % Refrence to app.MainModule.Preferences
        UniqueTargetCalibObj                    % Table returned by Planner.showCalibObj()        

        StartupNamespaceId                      %

        AppUtils                                %
        MsgBoxApp                               %         

        % Helper classes in astro/+ultrasat/+planner/+guiutils/
        StorageHelper                           % PlannerMainStorageHelper
        NewPlanHelper                           % PlannerMainNewPlanHelper        
        PlanParamsHelper                        % PlannerMainPlanParamsHelper
        UniqueTargetsHelper                     % PlannerMainUniqueTargetsHelper               
        PlanTargetsHelper                       % PlannerMainPlanTargetsHelper
        ApprovedTargetsHelper                   % PlannerMainApprovedTargetsHelper
        BuildHelper                             % PlannerMainBuildHelper
        PlotHelper                              % PlannerMainPlotHelper        
    end

    % =====================================================================
    %                         Init, Login, Log
    % =====================================================================
    methods (Access = public)
        function init(app)
            % Called from startupFcn() on application startup

            % Create MainModule that holds all common data
            app.UIFigure.Name = 'ULTRASAT Observation Planner';

            % Create MainModule that holds all common data and link objects
            app.MainModule = ultrasat.planner.guiutils.MainModule(app.StartupNamespaceId);
            app.MainModule.MainApp = app;

            % Create AppUtils and set in MainModule
            app.AppUtils = ultrasat.planner.guiutils.AppUtils(app.MainModule);
            app.AppUtils.App = app;
            app.MainModule.AppUtils = app.AppUtils;

            % Assign preferences loaded by MainModule
            app.Preferences = app.MainModule.Preferences;

            % Create log window
            app.LoggerApp = ultrasat.planner.gui.Logger(app.MainModule);
            app.LoggerApp.UIFigure.Visible = 'off';
            app.MainModule.LoggerApp = app.LoggerApp;
            app.msglog('init started');

            % Create helpers
            app.StorageHelper           = ultrasat.planner.guiutils.PlannerMainStorageHelper();            
            app.NewPlanHelper           = ultrasat.planner.guiutils.PlannerMainNewPlanHelper();
            app.PlanParamsHelper        = ultrasat.planner.guiutils.PlannerMainPlanParamsHelper();
            app.UniqueTargetsHelper     = ultrasat.planner.guiutils.PlannerMainUniqueTargetsHelper();
            app.PlanTargetsHelper       = ultrasat.planner.guiutils.PlannerMainPlanTargetsHelper();
            app.ApprovedTargetsHelper   = ultrasat.planner.guiutils.PlannerMainApprovedTargetsHelper();
            app.BuildHelper             = ultrasat.planner.guiutils.PlannerMainBuildHelper();
            app.PlotHelper              = ultrasat.planner.guiutils.PlannerMainPlotHelper();

            %
            app.TimerSec = timer('ExecutionMode', 'fixedRate', 'Period', 1, 'TimerFcn', @(~,~) updateTimerSec(app));
            start(app.TimerSec);

            % Set initial state for buttons and menus
            app.setLoginButtonStatus();
            app.setButtons();

            app.msglog('init done');
        end


        function updateTimerSec(app)
            currentTime = datetime('now', 'TimeZone', 'UTC');
            formattedTime = strcat('UTC: ', datestr(currentTime, 'yyyy-mm-dd HH:MM:SS'));
            app.LabelTopTime.Text = formattedTime;
        end

        % =================================================================
        %                            User Login
        % =================================================================

        function login(app)
            % User login
            app.msglog('login');

            % Do nothing if already connected
            if ~isempty(app.MainModule.UserName)
                return;
            end

            % Check active planner user name
            if app.hasPlanner()
                if ~strcmp(app.AppUtils.askYesNo('Note: you have an active plan, after connection the AstPlanner field will be set to the logged-in user name. Would you like to continue?', 'Confirmation'), 'Yes')
                    return;
                end
            end          

            % Create app
            if isempty(app.LoginApp) || ~isvalid(app.LoginApp)
                app.LoginApp = ultrasat.planner.gui.Login(app.MainModule);
            end            
            LoginStatus = app.showModal(app.LoginApp);
            app.msglog( sprintf('login uiwait returned: isempty: %d, isvalid: %d', isempty(app.LoginApp), isvalid(app.LoginApp)) );

            app.setLoginButtonStatus();
            app.setButtons();

            % Set AstPlanner to connected user
            if app.hasPlanner()
                if ~strcmp(app.MainModule.Planner.AstPlanner, app.MainModule.UserName)
                    app.msglog(sprintf('Login: Setting AstPlanner field of open plan: %s, %s', app.MainModule.Planner.AstPlanner, app.MainModule.UserName));
                    app.MainModule.Planner.AstPlanner = app.MainModule.UserName;
                end
            end            
        end


        function logout(app)
            % User logout
            app.msglog('logout');

            % Do nothing if not connected
            if isempty(app.MainModule.UserName)
                return;
            end

            if ~strcmp(app.AppUtils.askYesNo('Are you sure you want to logout?', 'Confirm'), 'Yes')
                return;
            end

            app.MainModule.logout();
            app.setLoginButtonStatus();
            app.setButtons();            
        end        


        function setLoginButtonStatus(app)
            % Connect button
            if ~isempty(app.MainModule.UserName)
                app.LoginButton.Text = 'Connected';
                app.LoginButton.BackgroundColor = [0.00, 1.00, 0.00];  % Green
                app.LabelTopUser.Text = app.MainModule.UserName;
            else
                app.LoginButton.Text = 'Login';
                app.LoginButton.BackgroundColor = [1.00, 1.00, 0.07];  % Yellow
                app.LabelTopUser.Text = 'Please login';
            end            

            % Namespace & Username
            if ~isempty(app.MainModule)
                % Set Namespace label colors
                app.LabelTopNamespace.Text = app.MainModule.NamespaceDisplay;
                if strcmp(app.MainModule.NamespaceId, 'OPER') 
                    app.LabelTopNamespace.FontColor = [1.00,1.00,1.00];  % White on black
                    app.LabelTopNamespace.BackgroundColor = [0.00,0.00,0.00];  
                else
                    app.LabelTopNamespace.FontColor = [0.00,0.00,0.00];  % Black on yellow
                    app.LabelTopNamespace.BackgroundColor = [1.00,1.00,0.07];
                end
            end
        end


        function Result = isLogin(app, Args)
            % Return true is user is loggned in, show popup message if Args.Message is true
            arguments
                app
                Args.Message = false
            end
            % Return true if used is logged-in
            Result = ~isempty(app.MainModule.UserName);
            if ~Result
                app.msglog('isLogin: not loggedin');
                if Args.Message
                    uialert(app.UIFigure, 'Login to the server is required to proceed with this operation', 'Message', 'Icon', 'success');            
                end
            end
        end


        function Result = isAllowed(app, Action)
            % Return true if specified action is allowed for current logged-in user
            % @TODO - To be replaced with checking permissions like Delphi code
            Result = ~isempty(app.MainModule.UserName);            
            if ~Result
                app.msglog(sprintf('isAllowed: not allowed: %', Action));
            end            
        end 


        function setButtons(app)
            % Enable/disable buttons and menu options based on current login status.

            enable = app.isLogin();

            % Apply to buttons
            %app.OpenButton.Enable = enable;
            app.SaveButton.Enable = app.hasPlanner();
            app.ParamsButton.Enable = app.hasPlanner();
            app.DuplicateButton.Enable = app.hasPlanner();
            app.ValidateButton.Enable = enable && app.hasPlanner();
            app.SubmitButton.Enable = enable && app.hasPlanner();

            % Apply to menu
            %app.OpenMenu.Enable = enable;
            app.SaveMenu.Enable = app.hasPlanner();
            app.ParamsButton.Enable = app.hasPlanner();
            app.DuplicateMenu.Enable = app.hasPlanner();
            app.ValidateMenu.Enable = enable && app.hasPlanner();
            app.SubmitMenu.Enable = enable && app.hasPlanner();
        end


        function showLogger(app)
            % Show log window
            app.msglog('showLogger');

            % Create app            
            if isempty(app.LoggerApp) || ~isvalid(app.LoggerApp)
                app.LoggerApp = ultrasat.planner.gui.Logger(app.MainModule);
            end            
            app.LoggerApp.UIFigure.Visible = 'on';
        end        


        function exitPlanner(app)
            % Exit the planner GUI
            %answer = questdlg('Are you sure you want to exit the Observaion Planner?', 'Confirm exit', 'Yes', 'No', 'No');            
            if ~strcmp(app.AppUtils.askYesNo('Are you sure you want to exit the planner application?', 'Confirmation'), 'Yes')
                return;
            end          

            % Shut down the entire app
            app.delete();
        end
    end

    % =====================================================================
    %             Create New Plan - HCS, LCS, DDT, AllSS, ToO
    % =====================================================================    

    methods (Access = public)

        function createNewPlan(app)
            app.NewPlanHelper.createNewPlan(app);
        end

    end

    % =====================================================================
    %           Plan - Open, Save, Close, Load, Duplicate Plan
    % =====================================================================    
    methods (Access = public)

        function openPlan(app)
            % Load plan from database, requires login and server connection
            app.StorageHelper.openPlan(app);
        end

        function savePlan(app)
            % Save current plan to database, requires login and server connection
            app.StorageHelper.savePlan(app);
        end

        function closePlan(app)
            %
            app.StorageHelper.closePlan(app);
        end

        function deletePlan(app)
            %
            app.StorageHelper.deletePlan(app);
        end

        function savePlanToFile(app)
            % Save current plan to text file, open dialog to ask user for file name
            app.StorageHelper.savePlanToFile(app);
        end

        function loadPlanFromFile(app)
            % Load plan from file as matlab object
            app.StorageHelper.loadPlanFromFile(app);
        end

        function duplicatePlan(app)
            % Duplicate the current observation plan.
            app.StorageHelper.duplicatePlan(app);
        end

        function showPlanAll(app)
            % Update GUI: Plan params, Unique Targets, Plan Targets, Approved Targets
            app.updatePlanParams();
            app.showUniqueTargets();
            app.showPlanTargets();
            app.showApprovedTargets();

            % Clear plots
            if ~app.hasPlanner()
                app.clearPlots();
            end
        end        
    end

    % =====================================================================    
    %                            Unique Targets    
    % =====================================================================
    methods (Access = public)
  
        function addUniqueTarget(app)
            % Add Unique-Target with addUniqTargets()
            app.UniqueTargetsHelper.addUniqueTarget(app);
        end

        function editUniqueTarget(app)
            % Edit Unique-Target with editUniqTarg()
            app.UniqueTargetsHelper.editUniqueTarget(app);
        end

        function setUniqueTargetParamsFields(app, UniqTarg, Index, ParamsApp)           
            % Helper: Set field values - Currently there are 9 fields for Unique Target
            app.UniqueTargetsHelper.setUniqueTargetParamsFields(app, UniqTarg, Index, ParamsApp);
        end        

        function deleteUniqueTarget(app)
            % Delete Unique-Target with delUniqTarg()
            app.UniqueTargetsHelper.deleteUniqueTarget(app);
        end

        function loadUniqueTargetsFromFile(app)
            % Load unique targets list from text file (csv). Open dialog to
            % ask user for file name or paste the text.
            app.UniqueTargetsHelper.loadUniqueTargetsFromFile(app);
        end

        function saveUniqueTargetsToFile(app)
            % Save unique targets list to text file (csv). Open dialog to
            % ask user for file name or paste the text.
            app.UniqueTargetsHelper.saveUniqueTargetsToFile(app);
        end

        function clearUniqueTargets(app)
            % CLEAR ALL Unique-Targets with clearUniqueTargets()
            app.UniqueTargetsHelper.clearUniqueTargets(app);
        end

        function uniqueTargetSelected(app, Index)
            % Helper: Called on Unique Target selection in table - @Todo
            app.UniqueTargetsHelper.uniqueTargetSelected(app, Index);
        end

        function showUniqueTargets(app)
            % Helper: Update the Unique Targets GUI table with data from Planner
            % Update the display of Unique Targets table
            app.UniqueTargetsHelper.showUniqueTargets(app);
        end

        function uniqueTargetClick(app)
            % Called on Unique-Target selection (single click) in the table
            app.UniqueTargetsHelper.uniqueTargetClick(app);
        end

        function uniqueTargetDoubleClick(app)
            % Called on Unique-Target double-click in the table
            app.UniqueTargetsHelper.uniqueTargetDoubleClick(app);
        end        

    end

    % =====================================================================
    %                            Plan Targets
    % =====================================================================
    methods (Access = public)

        function editPlanTarget(app)
            % Edit plan target by editPlanRow()
            app.PlanTargetsHelper.editPlanTarget(app);
        end

        function setPlanTargetParamsFiels(app, Plan, Index, ParamsApp)
            % Helper: Set field values - Currently there are 23 fields for Plan Target
            app.PlanTargetsHelper.setPlanTargetParamsFiels(app, Plan, Index, ParamsApp);
        end

        function applyPlanTargetParams(app, Index, ParamsApp)
            % Helper: Apply plan parameters from dialog to plan
            app.PlanTargetsHelper.applyPlanTargetParams(app, Index, ParamsApp);
        end

        function deletePlanTarget(app)
            % Delete plan target with delPlanRow()
            app.PlanTargetsHelper.deletePlanTarget(app);
        end

        function clearPlanTargets(app)
            % Clear all plan targets with clearPlan()
            app.PlanTargetsHelper.clearPlanTargets(app);
        end    

        function showPlanTargets(app)
            % Update the display of Plan Targets table
            app.PlanTargetsHelper.showPlanTargets(app)
        end

        function adjustGroupStartTime(app)
            % Adjust group of targets with adjustGroupStartTime()
            app.PlanTargetsHelper.adjustGroupStartTime(app);
        end

        function planTargetSelected(app, Index)
            % Called on plan target selection (single click)
            app.PlanTargetsHelper.planTargetSelected(app, Index);
        end

        function planRowClick(app)
            % Called on plan target selection (single click)
            app.PlanTargetsHelper.planRowClick(app);
        end
    
        function planRowDoubleClick(app)
            % Called on plan target double click
            app.PlanTargetsHelper.planRowDoubleClick(app);
        end
    end

    % =====================================================================
    %                               Windows
    % =====================================================================
    methods (Access = public)    

        function showUniqueTargetsWindow(app)
            % Show separate window with Unique Targets table
            app.UniqueTargetsHelper.showUniqueTargetsWindow(app);
        end        
       

        function showPlanTargetsWindow(app)
            % Show separate window with Plan Targets table
            app.PlanTargetsHelper.showPlanTargetsWindow(app);
        end                

        function showApprovedTargetsWindow(app)
            % Show separate window with Approved Targets table
            app.ApprovedTargetsHelper.showApprovedTargetsWindow(app);
        end                

        function copyUITable(app, SourceUITable, TargetUITable)
            % Copies data, column names, editability settings, and styles from SourceUITable to TargetUITable
            app.MainModule.GuiHelper.copyUITable(SourceUITable, TargetUITable);
        end
        
    end

    % =====================================================================
    %                               Build
    % =====================================================================
    methods (Access = public)

        function build(app)
            % Build plan according to plan type, calls doBuild...() below
            app.BuildHelper.build(app);
        end

        function setBuildStatus(app, Status)
            app.MainModule.PlanData.setStatus('BuildStatus', Status);
        end

        function showBuildStatusWindow(app)
            % Show window with last build status
            app.BuildHelper.showBuildStatusWindow(app);
        end


        function showValidationStatusWindow(app)
            % Show window with validation status
            app.msglog('showValidationStatusWindow');
            if ~app.hasPlanner(), return; end

            % Create app
            if isempty(app.ValidationStatusApp) || ~isvalid(app.ValidationStatusApp)
                app.ValidationStatusApp = ultrasat.planner.gui.ValidationStatus(app.MainModule);
            end            

            % Retrieve validation history from metadata
            ValidationHistory = app.MainModule.PlanData.metadata.ValidationResponse;        
            if isempty(ValidationHistory)
                app.msglog('No validation history available.');
                return;
            end
        
            % Extract struct from cell array if needed, convert to struct array
            if iscell(ValidationHistory)
                ValidationHistory = [ValidationHistory{:}];
            end

            % Setup table
            app.ValidationStatusApp.UITable.SelectionType = "row";
            app.ValidationStatusApp.UITable.Multiselect = "off";            
            app.ValidationStatusApp.UITable.RowName = "numbered";

            app.ValidationStatusApp.UITableHistory.SelectionType = "row";
            app.ValidationStatusApp.UITableHistory.Multiselect = "off";            
            app.ValidationStatusApp.UITableHistory.RowName = "numbered";            


            % Show latest validation response (first item in history)
            Response = ValidationHistory(1);
            app.showValidationResponse(Response);
        
            % Convert history to table (only keeping validation_time and status)
            HistoryData = struct2table(ValidationHistory, 'AsArray', true);
            HistoryData = HistoryData(:, {'validation_time', 'status'});
        
            % Assign history data to UITableHistory
            app.ValidationStatusApp.UITableHistory.Data = HistoryData;
        
            % Set column names for UITableHistory
            if ~isempty(HistoryData)
                app.ValidationStatusApp.UITableHistory.ColumnName = HistoryData.Properties.VariableNames;
            end

            app.showModal(app.ValidationStatusApp);
        end
        

        function validationHistorySelected(app)
            % Updates the displayed validation response based on selected row in history
            try
                % Retrieve validation history
                ValidationHistory = app.MainModule.PlanData.metadata.ValidationResponse;

                % Extract struct from cell array if needed, convert to struct array
                if iscell(ValidationHistory)
                    ValidationHistory = [ValidationHistory{:}];
                end

                % Ensure selection index is valid
                selection = app.ValidationStatusApp.UITableHistory.Selection;                
                if isempty(ValidationHistory) || (selection < 1) || (selection > numel(ValidationHistory))
                    app.msglog('Invalid history selection.');
                    return;
                end
        
                % Retrieve the selected validation response
                Response = ValidationHistory(selection);
        
                % Update display
                app.showValidationResponse(Response);
            catch ME
                app.msgex('validationHistorySelected', ME);
            end
        end


        function showValidationResponse(app, Response)
            % Update Validation app with details from response
            try
                % Reset fields to avoid stale data
                app.ValidationStatusApp.StartedEditField.Value = Response.validation_time;
                app.ValidationStatusApp.ElapsedEditField.Value = '';
                app.ValidationStatusApp.StatusEditField.Value = Response.status;
                app.ValidationStatusApp.StatusEditField.BackgroundColor = app.MainModule.GuiHelper.getValidationStatusBackgroundColor(Response.status);

                % Convert Response to JSON and HTML for display
                ResponseText = jsonencode(Response, 'PrettyPrint', true);
                app.ValidationStatusApp.TextArea.Value = ResponseText;
                Html = app.MainModule.jsonToHtml(Response);
                app.ValidationStatusApp.HTML.HTMLSource = Html;
        
                % Ensure targets exist in Response before converting to table
                if isfield(Response, 'task') && isfield(Response.task, 'targets') && ~isempty(Response.task.targets)
                    Data = struct2table(Response.task.targets, 'AsArray', true);
                    app.ValidationStatusApp.UITable.Data = Data;
        
                    % Update column names if data exists
                    if ~isempty(Data)
                        app.ValidationStatusApp.UITable.ColumnName = Data.Properties.VariableNames;
                    end

                    colIdx = find(strcmp(Data.Properties.VariableNames, 'status'), 1);       
                    if ~isempty(colIdx) % Ensure the column exists       
                        % Apply styles row by row based on the status value
                        for row = 1:height(Data)
                            status = string(Data{row, colIdx}); % Read status as string
                            style = app.MainModule.GuiHelper.getValidationStatusStyle(status);
                            addStyle(app.UITablePlanTargets, style, "cell", [row, colIdx]);
                        end
                    end                    
                else
                    app.ValidationStatusApp.UITable.Data = [];
                end
        
            catch ME
                app.msgex('showValidationResponse', ME);
            end
        end


        function showSubmitStatusWindow(app)
            % Show window with submit status
            app.msglog('dshowSubmitStatusWindow');
            if ~app.hasPlanner(), return; end

            % Create app
            if isempty(app.SubmitStatusApp) || ~isvalid(app.SubmitStatusApp)
                app.SubmitStatusApp = ultrasat.planner.gui.SubmitStatus(app.MainModule);
            end            

            % Set fields and show the app            
            %app.SubmitStatusApp.setData(app.MainModule.SubmitStatus);
            app.showModal(app.SubmitStatusApp);
        end        
    end
    
    % =====================================================================
    %                           Approved Targets
    % =====================================================================
    methods (Access = public)

        function retrieveApprovedTargets(app)
            % Retreive the list of approved taregts from the backend
            % User must be connected to server and logged-in
            app.ApprovedTargetsHelper.retrieveApprovedTargets(app);
        end

        function showApprovedTargets(app)
            % Update the GUI of Approved Targets table
            app.ApprovedTargetsHelper.showApprovedTargets(app);
        end

        function clearApprovedTargets(app)
            % Clear the list of approved targets
            app.ApprovedTargetsHelper.clearApprovedTargets(app);
        end

        function approvedTargetSelected(app, Index)
            % Called on selecting (single click) approved target from table
            app.ApprovedTargetsHelper.approvedTargetSelected(app, Index);
        end        


        function showOverriddenApprovedTargets(app, PlanTargetIndex)
            % Update the display with list of approved targets
            app.ApprovedTargetsHelper.showOverriddenApprovedTargets(app, PlanTargetIndex);
        end

    end

    % =====================================================================
    %                         Plan Params Window
    % =====================================================================
    methods (Access = public)
        
        function showPlanParamsWindow(app)
            % Show window with Plan Parameters
            app.PlanParamsHelper.showPlanParamsWindow(app);
        end

        function applyPlanParams(app, ParamsApp)
            % Helper: Apply plan parameters in current planner from PlanParams app
            % Called from showPlanParamsWindow            
            app.PlanParamsHelper.applyPlanParams(app, ParamsApp);
        end

        function setPlanStartEndTime(app, StartTimeValue, EndTimeValue)
            %
            app.PlanParamsHelper.setPlanStartEndTime(app, StartTimeValue, EndTimeValue);
        end

        function applyCheckTimes(app, ParamsApp)
            % Helper: Update Planner.CheckTimes with values from the edit fields
            % Note: Called from applyPlanParams() above
            % Note: REMOVED: Called from PlanParams.CheckTimesUpdateButtonPushed()
            app.PlanParamsHelper.applyCheckTimes(app, ParamsApp);
        end                

        function showPlanHistory(app)
            app.PlanParamsHelper.showPlanHistory(app);
        end
        
    end

    % =====================================================================
    %                           Other Windows
    % =====================================================================

    methods (Access = public)    
        function showAboutWindow(app)
            % Show the About window
            if isempty(app.AboutApp) || ~isvalid(app.AboutApp)
                app.AboutApp = ultrasat.planner.gui.About(app.MainModule);                
            end
            app.showModal(app.AboutApp);
        end


        function showHelp(app, item)
            % Open website in browser window, use system default browser (-browser)
            if isempty(item)
                web('https://docs.google.com/document/d/e/2PACX-1vTQKjJmBjzmcSXwaIRsq3FviYYpsW-Of7fewwcavCErBG7Pg589j3viLrUmNIr-NM-EfRWfQI4n0PdE/pub', '-browser');
            else
                item = ['http://socsrv/soc/data/help/planner/', item, '.html'];
                web(item, '-browser');
            end
        end


        function showGDriveQA(app)
            % Open website in browser window, use system default browser (-browser)
            web('https://docs.google.com/document/d/1iXs3Z5SHNnA8vUEf557DT3qIo9_rqELBp_WYg2vrYi0/edit?usp=sharing', '-browser');
        end        


        function showSnrCalculator(app)
            % Open website in browser window, with out SNR Calculator, use system default browser (-browser)
            web('https://www.weizmann.ac.il/ultrasat/for-scientists/snr-calculator', '-browser');
            %web('https://snrapp.ultrasatsoc.org/');            
        end

    end

    % =====================================================================
    %                               Plots
    % =====================================================================    
    methods (Access = public)

        function showSkyMapPlot(app)
            app.PlotHelper.showSkyMapPlot(app);
        end


        function showSkyMapPlotWindow(app)
            % Show stand-alone window with SkyMap plot, the user need to
            % click teh Update button in the embedded plot in this 
            app.msglog('plotCalibObj');
            if ~app.hasPlanner(), return; end

            % Create app
            if isempty(app.PlotSkyMapApp) || ~isvalid(app.PlotSkyMapApp)
                app.PlotSkyMapApp = ultrasat.planner.gui.PlotSkyMap(app.MainModule);                
            end
            app.PlotSkyMapApp.UIFigure.Visible = 'on';
        end

        % =================================================================      

        function plotGraphs(app)
            % Plot CalibObj or Visibility according to selected radio button
            try
                if ~app.hasPlanner()
                    app.clearPlots();
                    return;
                end

                % Plot CalibObj
                if app.CalibrationStarButton.Value
                    app.plotCalibObj();
                end

                % Plot Visibility
                if app.VisibilityButton.Value
                    app.plotVisibility();
                end                
            catch ME
                app.msgex('plotCalibObj', ME);
            end                
        end


        function clearPlots(app)
            % Clear the SkyMap and Graphs plots, on this window and the standalone windows.
            app.msglog('clearPlots');
            cla(app.AxesSkymapPlot, 'reset');
            cla(app.AxesGraphsPlot, 'reset');

            if ~isempty(app.PlotSkyMapApp) && isvalid(app.PlotSkyMapApp)
                cla(app.PlotSkyMapApp.AxesSkymapPlot, 'reset');
            end

            if ~isempty(app.PlotGraphsApp) && isvalid(app.PlotGraphsApp)
                cla(app.PlotGraphsApp.AxesGraphsPlot, 'reset');
            end
        end


        function plotCalibObj(app)
            app.PlotHelper.showCalibObjTable(app);
        end

        function showCalibObjTable(app)
            app.PlotHelper.showCalibObjTable(app);
        end

        function uniqueTargetSelectedInPlot(app, UniqueTargetIndex)
            app.PlotHelper.uniqueTargetSelectedInPlot(app, UniqueTargetIndex);
        end

        function plotCalibObjSub(app)
            app.PlotHelper.plotCalibObjSub(app);
        end

        function plotVisibility(app)
            app.PlotHelper.plotVisibility(app);
        end        


        function showGraphsPlotWindow(app)
            % Create app
            if isempty(app.PlotGraphsApp) || ~isvalid(app.PlotGraphsApp)
                app.PlotGraphsApp = ultrasat.planner.gui.PlotGraphs(app.MainModule);                
            end
            app.PlotGraphsApp.UIFigure.Visible = 'on';            
        end

    end

    % =====================================================================
    %                               
    % =====================================================================
    methods (Access = public)

        function updatePlanParams(app)
            % Helper: Update fields in top panel of with window with values from Plan parameters
            app.msglog('updatePlanParams');
            if ~app.hasPlanner(), return; end

            % Set fields
            Planner = app.MainModule.Planner;
            app.PlanTypeEditField.Value = Planner.Type;
            app.UserNameEditField.Value = Planner.AstPlanner;
            app.PlanTitleEditField.Value = Planner.Title;
            app.StartTimeEditField.Value = app.MainModule.DateTime2Str(Planner.StartTime);
            app.EndTimeEditField.Value = app.MainModule.DateTime2Str(Planner.EndTime);

            if app.isReadOnly()
                app.StartTimeEditField.Editable = "off";
                app.EndTimeEditField.Editable = "off";
                app.PlanTitleEditField.Editable = "off";
            else
                app.StartTimeEditField.Editable = "on";
                app.EndTimeEditField.Editable = "on";                
                app.PlanTitleEditField.Editable = "on";                
            end

            % Show message if plan was already submitted and cannot be modified
            if strcmp(Planner.Status, 'submitted')
                app.setTopLabel('The plan was submitted and cannot be modified.', [0.00,0.00,1.00], [1.00,1.00,0.07]);
            else
                app.setTopLabel('', [], []);
            end
        end

        
        function Result = checkPlanSelfConsistency(app)
            % Check plan for self consistency, update status display
            app.msglog('checkPlan')
            Result = false;
            try
                % Perform the check
                if height(app.MainModule.Planner.Plan) > 0
                    CheckStatus = app.MainModule.Planner.planSelfConsistencyCheck();
                end

                % Update display with status
                if CheckStatus
                    app.MainModule.setStatus('OK', 'self consistency: OK');
                    Result = true;
                else
                    app.MainModule.setStatus('Error', 'self consistency: issues found');
                end
            catch ME
                app.msgex('planSelfConsistencyCheck failed', ME);
                app.MainModule.setStatus('Error', sprintf('self consistency: exception: %s', ME.message));
            end            
        end

    end

    % =====================================================================
    %                           Validate % Submit    
    % =====================================================================
    methods (Access = public)    

        function validate(app)
            % Validate plan by sending it to the Validation service
            app.msglog('validate');
            if ~app.hasPlanner(), return; end            
            if app.isReadOnlyMsg(), return; end            
            if ~app.isLogin('Message', true), return; end
            
            % Ask user to confirm - currently not
            %if ~strcmp(app.AppUtils.askYesNo('Send plan with GCS Validator?', 'Confirm'), 'Yes')
            %    return;
            %end

            app.showPleaseWait('Validating your plan...');
            try
                app.MainModule.PlanData.addHistory('validation started');
                app.MainModule.Planner.validate();
                app.MainModule.PlanData.setStatus('ValidationStatus', 'OK');
            catch ME
                app.msgex('validate', ME);
            end
            app.closePleaseWait();
            app.updateStatus();
            app.AppUtils.msgOk('Validation completed, see detailed status in validation report window.')

            % User will open the status window
            % app.showValidationStatusWindow();
        end        


        function updateValidateStatus(app)
            % Update the validation status field
            if ~isempty(app.MainModule.Planner)
                app.setStatusField(app.ValidationShortStatusEditField, app.MainModule.ValidateStatus, app.MainModule.ValidateStatusText);
                app.setStatusField(app.ValidationTimeEditField, app.MainModule.ValidateStatus, app.MainModule.datetimeStr(app.MainModule.Planner.ValidatedTime));
            end

            % 
        end


        function submit(app)
            % Submit plan by sending it to Mission Control service
            % Debug: see files in D:\Ultrasat\AstroPack\matlab\astro\+ultrasat\+api\sim
            app.msglog('submit');
            if ~app.hasPlanner(), return; end            
            if app.isReadOnlyMsg(), return; end            
            if ~app.isLogin()
                return;
            end

            % Must save before submit, because backend need to access the
            % plan in the database.
            if app.MainModule.Modified
                if ~strcmp(app.AppUtils.askYesNo('The plan has been modified and not saved. You must save it before submitting. Do you want to save your changes?', 'Confirm'), 'Yes')
                    return;
                end                
            end
            app.savePlan();

            if ~strcmp(app.MainModule.Planner.Status, 'validated')
                if ~strcmp(app.AppUtils.askYesNo('The plan is not validated, or validation was not successful. Are you sure you want to submit this plan?', 'Confirm'), 'Yes')
                    return;
                end                
            end

            % Ask user for confirmation
            if ~strcmp(app.AppUtils.askYesNo('Submit this plan to Mission Control? Are you sure?', 'Confirm'), 'Yes')
                return;
            end

            app.showPleaseWait('Submitting your plan...');
            try
                % Send submit request to backend, uplanner.submit() calls
                % MissionClient.submitPlan().
                % After submit the plan should become read-only.
                app.MainModule.Planner.submit();
                app.MainModule.PlanData.setStatus('SubmitStatus', 'OK');
                app.addHistory('submit');
                app.setReadOnly(true);
            catch ME
                app.msgex('submit', ME);
            end
            app.closePleaseWait();
            app.updateStatus();
        end        
    end

    % =====================================================================
    %                               Status
    % =====================================================================    

    methods (Access = public)            

        function applyPlanStatus(app)
            % Helper:
            if app.hasPlanner()
                if strcmp(app.MainModule.Planner.Status, 'Submitted')
                    app.setReadOnly(true);
                else
                    app.setReadOnly(false);
                end
            else
            end
        end


        function setReadOnly(app, ReadOnly)
            % Helper: Setc/clear read-only status of the current plan
            app.AllowEdit = ~ReadOnly;            
        end


        function Result = isReadOnly(app)
            % Helper: Return true if currently in read-only mode
            Result = ~app.AllowEdit;
        end

        
        function Result = isReadOnlyMsg(app)
            % Helper: Return true if currently in read-only mode, show popup message
            Result = ~app.AllowEdit;
            if Result
                uialert(app.UIFigure, sprintf('Plan is read-only: %s', app.AllowEditMsg), 'Message', 'Icon', 'success');                            
            end
        end        


        function setModified(app, logText)
            % Helper: Mark the plan as modified (i.e. required to be saved/discarded)
            % if ~app.MainModule.Modified
                if nargin < 2 || isempty(logText)
                    logText = '';
                end
                app.msglog(sprintf('setModified: modified - %s', logText));
                app.MainModule.setModified();
                app.ModifiedLabel.Text = 'Modified';

                app.SaveButton.Enable = 'on';
            % end
        end


        function clearModified(app)
            % Helper: Clear the Modified flag and status
            if app.MainModule.Modified
                app.msglog('clearModified')
            end            
            app.MainModule.clearModified();
            app.ModifiedLabel.Text = '';
            app.SaveButton.Enable = 'off';
        end


        function Result = needSave(app, AskSave)
            % Helper: Check if current plan has been modified and need to be saved
            if app.MainModule.Modified
                if AskSave
                    if strcmp(app.AppUtils.askYesNo('Your changes are not saved. Save or discard?', 'Save or discard'), 'Yes')
                        try
                            app.savePlan();
                        catch ME
                            app.msgex('needSave', ME);
                        end
                        Result = true;
                    else
                        Result = true;
                    end
                else
                    Result = true;
                end
            else
                Result = false;
            end
        end


        function setStatus(app, Status, Text)
            % Helper: Update the status panel with new status
            app.msglog(sprintf('setStatus: %s - %s', Status, Text));
            app.MainModule.setStatus(Status, Text);
            app.updateStatus();
        end


        function setStatusEx(app, Title, ME)
            % Helper: Update the status panel with exception message
            app.MainModule.setStatus('Error', sprintf('%s - %s', Title, ME.message));
            app.updateStatus();
        end


        function updateStatus(app)
            % @Todo - ??

            app.setStatusField(app.StatusTextArea, app.MainModule.CurrentStatus, app.MainModule.StatusText);

            PlanData = app.MainModule.PlanData;
            Planner = app.MainModule.Planner;

            % Planner is not empty, set fields
            if ~isempty(Planner)
                app.BuildTimeEditField.Value = app.MainModule.DateTime2Str(Planner.ScheduledTime);
                app.ValidationTimeEditField.Value = app.MainModule.DateTime2Str(Planner.ValidatedTime);
                app.SubmitTimeEditField.Value = app.MainModule.DateTime2Str(Planner.SubmittedTime);

                %
                app.setStatusField(app.BuildShortStatusEditField, PlanData.metadata.BuildStatus.Status, PlanData.metadata.BuildStatus.Status);
                app.setStatusField(app.ValidationShortStatusEditField, PlanData.metadata.ValidationStatus.Status, PlanData.metadata.ValidationStatus.Status);
                app.setStatusField(app.SubmitShortStatusEditField, PlanData.metadata.SubmitStatus.Status, PlanData.metadata.SubmitStatus.Status);
                
                if strcmp(Planner.Status, 'submitted')
                    app.setTopLabel('The plan was submitted and cannot be modified.', [0.00,0.00,1.00], [1.00,1.00,0.07]);
                else
                    app.setTopLabel('', [], []);
                end            

            % Planner is empty, clear fields
            else
                app.BuildTimeEditField.Value = '';
                app.ValidationTimeEditField.Value = '';
                app.SubmitTimeEditField.Value = '';

                app.setStatusField(app.BuildShortStatusEditField, '', '');
                app.setStatusField(app.ValidationShortStatusEditField, '', '');
                app.setStatusField(app.SubmitShortStatusEditField, '', '');                

                app.setTopLabel('', [], []);
            end

        end


        function setStatusField(app, EditField, Status, StatusText)
            % Helper: Set the background color of the EditField based on the Status value.
            % Valid values for Status: OK, Warning, Error, (empty)
            app.MainModule.GuiHelper.setStatusField(app, EditField, Status, StatusText);
        end

    end

    % =====================================================================
    %                   Low Level & Utility Functions
    % =====================================================================

    methods (Access = public)        

        function Result = checkSelectedTableRow(app, Tab, Index)
            % Check that index is valid row number in the specified table
            Result = ~isempty(Index) && Index >= 1 && Index <= height(Tab);
        end


        function Result = getSelectedTableRowAsStruct(app, Tab, Index)
            % Convert the selected table row to struct, return [] if Index
            % is not a valid row number in Tab
            Result = [];
            if ~isempty(Index) && Index >= 1 && Index <= height(Tab)
                Result = table2struct(Tab(Index, :));
            end                
        end               

        function Status = showModal(app, FormApp)
            % Helper: Show modal app window and return FormApp.Status
            % Call FormApp.beforeShow() if such function exists in FormApp
            % Note: FormApp should have 'Status' property
            Status = app.MainModule.GuiHelper.showModal(app, FormApp);
        end

        function handleCloseRequest(app, FormApp)
            % Used from showModal() above
            % Handle 'X' button close event
            if isvalid(FormApp)  % Ensure app is still valid before modifying properties
                FormApp.Status = 'Cancel';                
                uiresume(FormApp.UIFigure);  % Resume execution
                %delete(FormApp);  % Delete the app safely
            end
        end
        
        function setTopLabel(app, Text, FontColor, BackgroundColor)
            % Helper: Set text and colors of LabelTopStatus (located just below the main toolbar)
            % Hide the label if Text is empty.
            % Colors: Font: Blue: [0.00,0.00,1.00], Background: Yellow: [1.00,1.00,0.07]
            % Example: app.setTopLabel('The plan was submitted and cannot be modified.', [0.00,0.00,1.00], [1.00,1.00,0.07])
            if isempty(Text)
                app.LabelTopStatus.Visible = false;
            else
                app.LabelTopStatus.Text = Text;
                app.LabelTopStatus.FontColor = FontColor;
                app.LabelTopStatus.BackgroundColor = BackgroundColor;
                app.LabelTopStatus.Visible = true;                
            end
        end

        % =================================================================
        %                    Low Level Utilities
        % =================================================================        
        function msglog(app, s)
            % Log message to console & file
            app.MainModule.msglog(s);            
        end


        function msgex(app, s, ME)
            % Log exception to console & file, display popup message to the user
            app.setStatus('Error', ME.message)
            app.MainModule.msglog(sprintf('Exception: %s - %s', s, ME.message));
            app.AppUtils.msgError(ME.message, s);
        end        


        function debugSave(app, FileName, Obj)
            % Helper: Save the specified Obj to file in DebugPath
            FileName = fullfile(app.MainModule.DebugPath, FileName);
            app.msglog(sprintf('debugSave: %s', FileName));
            save(FileName, 'Obj')
        end


        function showPleaseWait(app, Message)
            % Helper: Shows a spinner popup with 'Please Wait' message
            app.PleaseWaitDlg = uiprogressdlg(app.UIFigure, 'Title', 'Please wait', 'Message', Message, 'Indeterminate', 'on');  
        end


        function closePleaseWait(app)
            % Helper: Close the Please Wait popup message
            if ~isempty(app.PleaseWaitDlg)
                close(app.PleaseWaitDlg);
                app.PleaseWaitDlg = [];
            end
        end


        function result = hasPlanner(app)
            % Helper: Return true if there is active planner object
            result = ~isempty(app.MainModule.Planner);
            if ~result
                %app.msglog('hasPlanner: None');
            end
        end


        function result = hasPlan(app)
            % Helper: Return true if there is active planner object and
            % plan targets list is not empty.
            result = ~isempty(app.MainModule.Planner) && (height(app.MainModule.Planner.Plan) > 0);
            if ~result
                app.msglog('hasPlan: None');
            end
        end        
        

        function addHistory(app, msg)
            % Add message to current plan history log
            if app.hasPlanner()
                app.MainModule.PlanData.addHistory(msg);
            end
        end        


        function savePreferences(app)
            % Save user preferences to local file
            app.Preferences.save();
        end        
    end

    % =====================================================================
    %
    %               AppDesigner Callbacks are Generated Below
    %
    % =====================================================================    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % This function is automaticalled called on application startup
            app.init();

            if ~isempty(varargin)
                app.StartupNamespaceId = varargin{1};
                fprintf('[startupFcn] NamespaceId specified: "%s"\n', NamespaceId);
            else
                app.StartupNamespaceId = 'sim-OPER';
                fprintf('[startupFcn] No NamespaceId specified — using default ""\n');
            end          
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            app.createNewPlan();
        end

        % Callback function
        function UniqueButtonPushed(app, event)

        end

        % Menu selected function: ConnectLoginMenu
        function ConnectLoginMenuSelected(app, event)
            app.login();
        end

        % Menu selected function: DisconnectLogoutMenu
        function DisconnectLogoutMenuSelected(app, event)
            app.logout();
        end

        % Callback function
        function NewMenuSelected(app, event)
            app.createNewPlan();
        end

        % Callback function
        function OpenMenuSelected(app, event)
            app.openPlan();           
        end

        % Callback function
        function SaveMenuSelected(app, event)
            app.savePlan();
        end

        % Callback function
        function ExitPlannerMenuSelected(app, event)
            app.exitPlanner();
        end

        % Callback function
        function UniqueTargetsMenuSelected(app, event)
            app.showUniqueTargetsWindow();
        end

        % Callback function
        function ParametersMenuSelected(app, event)
            app.showPlanParamsWindow();
        end

        % Callback function
        function ValidateMenuSelected(app, event)
            app.validate();
        end

        % Callback function
        function SubmitMenuSelected(app, event)
            app.submit();
        end

        % Size changed function: ApprovedTargetsPanel
        function ApprovedTargetsPanelSizeChanged(app, event)

        end

        % Size changed function: PlanPanel
        function PlanPanelSizeChanged(app, event)

        end

        % Size changed function: UniqueTargetsPanel
        function UniqueTargetsPanelSizeChanged(app, event)

        end

        % Callback function
        function SkyMapButtonPushed(app, event)
            app.showSkyMapPlot();
        end

        % Callback function
        function GraphsButtonPushed(app, event)
            app.showGraphsPlot();
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            app.showAboutWindow();
        end

        % Callback function
        function AddUniqueButtonPushed(app, event)
            app.addUniqueTarget();
        end

        % Button pushed function: AddUniqueTargetButton
        function AddUniqueTargetButtonPushed(app, event)
            app.addUniqueTarget();
        end

        % Button pushed function: EditUniqueTargetButton
        function EditUniqueTargetButtonPushed(app, event)
            app.editUniqueTarget();
        end

        % Callback function
        function RefreshUniqueTargetsButtonPushed(app, event)
            app.showUniqueTargets();
        end

        % Selection changed function: UITableUniqueTargets
        function UITableUniqueTargetsSelectionChanged(app, event)
            %app.UniqueTargetsIndices = event.Indices;
            selection = app.UITableUniqueTargets.Selection;
            app.msglog(sprintf('Unique target selected: %d', selection));
            app.uniqueTargetSelected(selection);
        end

        % Button pushed function: SNRCalcButton
        function SNRCalcButtonPushed(app, event)
            app.showSnrCalculator();
        end

        % Callback function
        function RefreshPlanTargetsButtonPushed(app, event)
            app.showPlanTargets();
        end

        % Button pushed function: RefreshApprovedTargetsButton
        function RefreshApprovedTargetsButtonPushed(app, event)
            app.retrieveApprovedTargets();
        end

        % Menu selected function: ObservationPlannerHelpMenu
        function ObservationPlannerHelpMenuSelected(app, event)
            app.showHelp();
        end

        % Callback function
        function NewMenuSelected2(app, event)

        end

        % Callback function
        function OpenMenuSelected2(app, event)
            %
        end

        % Callback function
        function SaveMenuSelected2(app, event)
            app.savePlan();
        end

        % Callback function
        function CloseMenu_2Selected(app, event)
            %
        end

        % Callback function
        function DeleteMenuSelected(app, event)
            app.deletePlan();
        end

        % Callback function
        function SavePlantoFileMenuSelected(app, event)
            app.savePlanToFile();
        end

        % Callback function
        function LoadPlanfromFileMenuSelected(app, event)
            app.loadPlanFromFile();
        end

        % Menu selected function: AddUniqueTargetMenu
        function AddUniqueTargetMenuSelected(app, event)
            app.addUniqueTarget();
        end

        % Menu selected function: EditUniqueTargetMenu
        function EditUniqueTargetMenuSelected(app, event)
            app.editUniqueTarget();
        end

        % Menu selected function: DeleteUniqueTargetMenu
        function DeleteUniqueTargetMenuSelected(app, event)
            app.deleteUniqueTarget();
        end

        % Menu selected function: ClearAllUniqueTargetsMenu
        function ClearAllUniqueTargetsMenuSelected(app, event)
            app.clearUniqueTargets();
        end

        % Menu selected function: ViewUniqueTargetsTableMenu
        function ViewUniqueTargetsTableMenuSelected(app, event)
            app.showUniqueTargetsWindow();
        end

        % Menu selected function: SaveUniqueTargetsToFileMenu
        function SaveUniqueTargetsToFileMenuSelected(app, event)
            app.saveUniqueTargetsToFile();
        end

        % Menu selected function: LoadUniqueTargetsFromFileMenu
        function LoadUniqueTargetsFromFileMenuSelected(app, event)
            %
            app.loadUniqueTargetsFromFile();
        end

        % Menu selected function: ViewSkyMapPlotWindowMenu
        function ViewSkyMapPlotWindowMenuSelected(app, event)
            app.showSkyMapPlotWindow();
        end

        % Menu selected function: ViewGraphsPlotWindowMenu
        function ViewGraphsPlotWindowMenuSelected(app, event)
            app.showGraphsPlotWindow();
        end

        % Menu selected function: EditPlanTargetMenu
        function EditPlanTargetMenuSelected(app, event)
            app.editPlanTarget();
        end

        % Menu selected function: DeletePlanTargetMenu
        function DeletePlanTargetMenuSelected(app, event)
            %
            app.deletePlanTarget();
        end

        % Menu selected function: ClearAllPlanTargetsMenu
        function ClearAllPlanTargetsMenuSelected(app, event)
            app.clearPlanTargets();
        end

        % Menu selected function: ViewPlanTableMenu
        function ViewPlanTableMenuSelected(app, event)
            app.showPlanTargetsWindow();
        end

        % Callback function
        function NewButtonPushed2(app, event)
           
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.openPlan();            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.savePlan();
        end

        % Button pushed function: ParamsButton
        function ParamsButtonPushed(app, event)
            app.showPlanParamsWindow();
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            app.validate();
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            app.submit();
        end

        % Callback function
        function GetApprovedButtonPushed(app, event)

        end

        % Button pushed function: BuildButton
        function BuildButtonPushed(app, event)
            app.build();
        end

        % Button pushed function: EditPlanTargetButton
        function EditPlanTargetButtonPushed(app, event)
            app.editPlanTarget();
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            if app.isLogin()
                app.logout();
            else
                app.login();
            end
        end

        % Callback function
        function BuildMenuSelected(app, event)
            app.build();
        end

        % Button pushed function: LoadUniqueTargetsButton
        function LoadUniqueTargetsButtonPushed(app, event)
            app.loadUniqueTargetsFromFile();
        end

        % Menu selected function: LogWindowMenu
        function LogWindowMenuSelected(app, event)
            app.showLogger();
        end

        % Callback function
        function PlotCalibButtonPushed(app, event)
            app.plotCalibObj();
        end

        % Button pushed function: RefreshApprovedTargetsButton_2
        function RefreshApprovedTargetsButton_2Pushed(app, event)
            app.MainModule.clearStatus();
            app.updateStatus();
        end

        % Button pushed function: CheckPlanTargetsButton
        function CheckPlanTargetsButtonPushed(app, event)
            app.checkPlanSelfConsistency();
        end

        % Selection changed function: UITablePlanTargets
        function UITablePlanTargetsSelectionChanged(app, event)
            selection = app.UITablePlanTargets.Selection;
            app.msglog(sprintf('Plan target selected: %d', selection));
            app.planTargetSelected(selection);                        
        end

        % Selection changed function: UITableApprovedTargets
        function UITableApprovedTargetsSelectionChanged(app, event)
            selection = app.UITableApprovedTargets.Selection;
            app.msglog(sprintf('Approved target selected: %d', selection));
            app.approvedTargetSelected(selection);            
        end

        % Button pushed function: DuplicateButton
        function DuplicateButtonPushed(app, event)
            app.duplicatePlan();
        end

        % Callback function
        function CheckTimesUpdateButtonPushed(app, event)
            app.updatePlannerCheckTimes();
        end

        % Callback function
        function PlotVisibilityButtonPushed(app, event)
            app.plotVisibility();
        end

        % Value changed function: PlotCalibObjDropDown
        function PlotCalibObjDropDownValueChanged(app, event)
            app.plotCalibObjSub();
        end

        % Button pushed function: UpdateSkyMapButton
        function UpdateSkyMapButtonPushed(app, event)
            app.showSkyMapPlot();
        end

        % Button pushed function: RefreshApprovedTargetsButton_3
        function RefreshApprovedTargetsButton_3Pushed(app, event)
            app.clearApprovedTargets();
        end

        % Button pushed function: EditPlanTargetButton_6
        function EditPlanTargetButton_6Pushed(app, event)
            app.adjustGroupStartTime();
        end

        % Menu selected function: RefreshMenu
        function RefreshMenuSelected(app, event)
            app.showPlanAll();
        end

        % Button pushed function: EditPlanTargetButton_5
        function EditPlanTargetButton_5Pushed(app, event)
            app.clearUniqueTargets();
        end

        % Button pushed function: EditPlanTargetButton_3
        function EditPlanTargetButton_3Pushed(app, event)
            app.clearPlanTargets();
        end

        % Clicked callback: UITableUniqueTargets
        function UITableUniqueTargetsClicked(app, event)
            %
            %displayRow = event.InteractionInformation.DisplayRow;
            %displayColumn = event.InteractionInformation.DisplayColumn;            
            app.uniqueTargetClick();
        end

        % Double-clicked callback: UITableUniqueTargets
        function UITableUniqueTargetsDoubleClicked(app, event)
            %
            %displayRow = event.InteractionInformation.DisplayRow;
            %displayColumn = event.InteractionInformation.DisplayColumn;
            app.uniqueTargetDoubleClick();
        end

        % Clicked callback: UITablePlanTargets
        function UITablePlanTargetsClicked(app, event)
            %displayRow = event.InteractionInformation.DisplayRow;
            %displayColumn = event.InteractionInformation.DisplayColumn;
            app.planRowClick();
        end

        % Double-clicked callback: UITablePlanTargets
        function UITablePlanTargetsDoubleClicked(app, event)
            %displayRow = event.InteractionInformation.DisplayRow;
            %displayColumn = event.InteractionInformation.DisplayColumn;
            app.planRowDoubleClick();            
        end

        % Button pushed function: BuildStatusButton
        function BuildStatusButtonPushed(app, event)
            app.showBuildStatusWindow();
        end

        % Button pushed function: ValidationStatusButton
        function ValidationStatusButtonPushed(app, event)
            app.showValidationStatusWindow();
        end

        % Button pushed function: SubmitStatusButton
        function SubmitStatusButtonPushed(app, event)
            app.showSubmitStatusWindow();
        end

        % Menu selected function: NewMenu
        function NewMenuSelected3(app, event)
            app.createNewPlan();
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected3(app, event)
            app.openPlan();
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected3(app, event)
            app.savePlan();
        end

        % Menu selected function: DuplicateMenu
        function DuplicateMenuSelected(app, event)
            app.duplicatePlan();
        end

        % Menu selected function: CloseMenu_2
        function CloseMenu_2Selected2(app, event)
            app.closePlan();
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected2(app, event)
            app.deletePlan();
        end

        % Menu selected function: SaveToLocalFileMenu
        function SaveToLocalFileMenuSelected(app, event)
            app.savePlanToFile();
        end

        % Menu selected function: OpenFromLocalFileMenu
        function OpenFromLocalFileMenuSelected(app, event)
            app.loadPlanFromFile();
        end

        % Menu selected function: ExitPlannerMenu
        function ExitPlannerMenuSelected2(app, event)
            app.exitPlanner();
        end

        % Button pushed function: OpenCalObjTableButton
        function OpenCalObjTableButtonPushed(app, event)
            app.showCalibObjTable();
        end

        % Button pushed function: OpenSkyMapPlotWindowButton
        function OpenSkyMapPlotWindowButtonPushed(app, event)
            app.showSkyMapPlotWindow();
        end

        % Button pushed function: OpenGraphsPlotWindowButton
        function OpenGraphsPlotWindowButtonPushed(app, event)
            app.showGraphsPlotWindow();
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ButtonGroup.SelectedObject;
            app.plotGraphs();
        end

        % Button pushed function: EditPlanTargetButton_2
        function EditPlanTargetButton_2Pushed(app, event)
            app.deletePlanTarget();
        end

        % Button pushed function: GDriveCommentsButton
        function GDriveCommentsButtonPushed(app, event)
            app.showGDriveQA();
        end

        % Value changed function: PlanTitleEditField
        function PlanTitleEditFieldValueChanged(app, event)
            value = app.PlanTitleEditField.Value;
            if ~isempty(app.MainModule.Planner)
                app.MainModule.Planner.Title = value;
                app.msglog(sprintf('PlanTitleEditFieldValueChanged: %s', value));
            end
        end

        % Menu selected function: PlanHistoryMenu
        function PlanHistoryMenuSelected(app, event)
            app.showPlanHistory();
        end

        % Button pushed function: EditPlanTargetButton_4
        function EditPlanTargetButton_4Pushed(app, event)
            app.DeleteUniqueTarget();
        end

        % Menu selected function: ClearPlotsMenu
        function ClearPlotsMenuSelected(app, event)
            app.clearPlots();
        end

        % Button pushed function: ShowUniqueTargetsWindowButton
        function ShowUniqueTargetsWindowButtonPushed(app, event)
            app.showUniqueTargetsWindow();
        end

        % Button pushed function: ShowPlanRowsWindowButton
        function ShowPlanRowsWindowButtonPushed(app, event)
            app.showPlanTargetsWindow();
        end

        % Button pushed function: ShowApprovedTargetsWindowButton
        function ShowApprovedTargetsWindowButtonPushed(app, event)
            app.showApprovedTargetsWindow();
        end

        % Callback function
        function StartTimeEditFieldValueChanged(app, event)
            value = app.StartTimeEditField.Value;
            value = app.PlanTitleEditField.Value;
            if ~isempty(app.MainModule.Planner)
                app.MainModule.Planner.Title = value;
                app.msglog(sprintf('PlanTitleEditFieldValueChanged: %s', value));
            end            
        end

        % Value changed function: EndTimeEditField
        function EndTimeEditFieldValueChanged(app, event)
            app.setPlanStartEndTime(app.StartTimeEditField.Value, app.EndTimeEditField.Value);            
        end

        % Value changed function: StartTimeEditField
        function StartTimeEditFieldValueChanged2(app, event)
            app.setPlanStartEndTime(app.StartTimeEditField.Value, app.EndTimeEditField.Value);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.showHelp('');
        end

        % Button pushed function: HelpUniqueTargetsWindowButton
        function HelpUniqueTargetsWindowButtonPushed(app, event)
            app.showHelp('add_unique_target');
        end

        % Button pushed function: HelpUniqueTargetsWindowButton_2
        function HelpUniqueTargetsWindowButton_2Pushed(app, event)
            app.showHelp('build_plan');
        end

        % Button pushed function: HelpUniqueTargetsWindowButton_3
        function HelpUniqueTargetsWindowButton_3Pushed(app, event)
            app.showHelp('approved_targets');
        end

        % Button pushed function: HelpSkyMapPlotWindowButton
        function HelpSkyMapPlotWindowButtonPushed(app, event)
            app.showHelp('skymap_plot');
        end

        % Button down function: AxesGraphsPlot
        function AxesGraphsPlotButtonDown(app, event)
            app.showHelp('graphs_plot');
        end

        % Button pushed function: HelpPlanParamsButton
        function HelpPlanParamsButtonPushed(app, event)
           app.showHelp('plan_params'); 
        end

        % Button pushed function: HelpStatusInfoButton
        function HelpStatusInfoButtonPushed(app, event)
            app.showHelp('status_info');
        end

        % Button pushed function: HelpGraphsPlotWindowButton
        function HelpGraphsPlotWindowButtonPushed(app, event)
            app.showHelp('graphs_plot');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected3, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected3, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected3, true);
            app.SaveMenu.Text = 'Save';
            app.DuplicateMenu = uimenu(app.FileMenu);
            app.DuplicateMenu.MenuSelectedFcn = createCallbackFcn(app, @DuplicateMenuSelected, true);
            app.DuplicateMenu.Text = 'Duplicate';
            app.CloseMenu_2 = uimenu(app.FileMenu);
            app.CloseMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CloseMenu_2Selected2, true);
            app.CloseMenu_2.Text = 'Close';
            app.DeleteMenu = uimenu(app.FileMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected2, true);
            app.DeleteMenu.Text = 'Delete';
            app.SaveToLocalFileMenu = uimenu(app.FileMenu);
            app.SaveToLocalFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveToLocalFileMenuSelected, true);
            app.SaveToLocalFileMenu.Text = 'Save To Local File';
            app.OpenFromLocalFileMenu = uimenu(app.FileMenu);
            app.OpenFromLocalFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFromLocalFileMenuSelected, true);
            app.OpenFromLocalFileMenu.Text = 'Open From Local File';
            app.Menu_2 = uimenu(app.FileMenu);
            app.Menu_2.Text = '______________________';
            app.ConnectLoginMenu = uimenu(app.FileMenu);
            app.ConnectLoginMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectLoginMenuSelected, true);
            app.ConnectLoginMenu.Text = 'Connect && Login';
            app.DisconnectLogoutMenu = uimenu(app.FileMenu);
            app.DisconnectLogoutMenu.MenuSelectedFcn = createCallbackFcn(app, @DisconnectLogoutMenuSelected, true);
            app.DisconnectLogoutMenu.Text = 'Disconnect (Logout)';
            app.ExitPlannerMenu = uimenu(app.FileMenu);
            app.ExitPlannerMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitPlannerMenuSelected2, true);
            app.ExitPlannerMenu.Text = 'Exit Planner';
            app.TargetsMenu = uimenu(app.UIFigure);
            app.TargetsMenu.Text = 'Targets';
            app.AddUniqueTargetMenu = uimenu(app.TargetsMenu);
            app.AddUniqueTargetMenu.MenuSelectedFcn = createCallbackFcn(app, @AddUniqueTargetMenuSelected, true);
            app.AddUniqueTargetMenu.Text = 'Add Unique Target';
            app.EditUniqueTargetMenu = uimenu(app.TargetsMenu);
            app.EditUniqueTargetMenu.MenuSelectedFcn = createCallbackFcn(app, @EditUniqueTargetMenuSelected, true);
            app.EditUniqueTargetMenu.Text = 'Edit Unique Target';
            app.DeleteUniqueTargetMenu = uimenu(app.TargetsMenu);
            app.DeleteUniqueTargetMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteUniqueTargetMenuSelected, true);
            app.DeleteUniqueTargetMenu.Text = 'Delete Unique Target';
            app.ClearAllUniqueTargetsMenu = uimenu(app.TargetsMenu);
            app.ClearAllUniqueTargetsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAllUniqueTargetsMenuSelected, true);
            app.ClearAllUniqueTargetsMenu.Text = 'Clear All Unique Targets';
            app.ViewUniqueTargetsTableMenu = uimenu(app.TargetsMenu);
            app.ViewUniqueTargetsTableMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewUniqueTargetsTableMenuSelected, true);
            app.ViewUniqueTargetsTableMenu.Text = 'View Unique Targets Table';
            app.SaveUniqueTargetsToFileMenu = uimenu(app.TargetsMenu);
            app.SaveUniqueTargetsToFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveUniqueTargetsToFileMenuSelected, true);
            app.SaveUniqueTargetsToFileMenu.Text = 'Save Unique Targets To File';
            app.LoadUniqueTargetsFromFileMenu = uimenu(app.TargetsMenu);
            app.LoadUniqueTargetsFromFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadUniqueTargetsFromFileMenuSelected, true);
            app.LoadUniqueTargetsFromFileMenu.Text = 'Load Unique Targets From File';
            app.PlanMenu_2 = uimenu(app.UIFigure);
            app.PlanMenu_2.Text = 'Plan';
            app.EditPlanTargetMenu = uimenu(app.PlanMenu_2);
            app.EditPlanTargetMenu.MenuSelectedFcn = createCallbackFcn(app, @EditPlanTargetMenuSelected, true);
            app.EditPlanTargetMenu.Text = 'Edit Plan Target';
            app.DeletePlanTargetMenu = uimenu(app.PlanMenu_2);
            app.DeletePlanTargetMenu.MenuSelectedFcn = createCallbackFcn(app, @DeletePlanTargetMenuSelected, true);
            app.DeletePlanTargetMenu.Text = 'Delete Plan Target';
            app.ClearAllPlanTargetsMenu = uimenu(app.PlanMenu_2);
            app.ClearAllPlanTargetsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAllPlanTargetsMenuSelected, true);
            app.ClearAllPlanTargetsMenu.Text = 'Clear All Plan Targets';
            app.ViewPlanTableMenu = uimenu(app.PlanMenu_2);
            app.ViewPlanTableMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewPlanTableMenuSelected, true);
            app.ViewPlanTableMenu.Text = 'View Plan Table';
            app.Menu_3 = uimenu(app.PlanMenu_2);
            app.Menu_3.Text = '___________________';
            app.ParamsMenu = uimenu(app.PlanMenu_2);
            app.ParamsMenu.Text = 'Parameters';
            app.PlanHistoryMenu = uimenu(app.PlanMenu_2);
            app.PlanHistoryMenu.MenuSelectedFcn = createCallbackFcn(app, @PlanHistoryMenuSelected, true);
            app.PlanHistoryMenu.Text = 'History';
            app.Menu = uimenu(app.PlanMenu_2);
            app.Menu.Text = '___________________';
            app.BuildMenu = uimenu(app.PlanMenu_2);
            app.BuildMenu.Text = 'Build';
            app.ValidateMenu = uimenu(app.PlanMenu_2);
            app.ValidateMenu.Text = 'Validate';
            app.SubmitMenu = uimenu(app.PlanMenu_2);
            app.SubmitMenu.Text = 'Submit';
            app.PlotsMenu = uimenu(app.UIFigure);
            app.PlotsMenu.Text = 'Plots';
            app.ViewSkyMapPlotWindowMenu = uimenu(app.PlotsMenu);
            app.ViewSkyMapPlotWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewSkyMapPlotWindowMenuSelected, true);
            app.ViewSkyMapPlotWindowMenu.Text = 'View Sky Map Plot Window';
            app.ViewGraphsPlotWindowMenu = uimenu(app.PlotsMenu);
            app.ViewGraphsPlotWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewGraphsPlotWindowMenuSelected, true);
            app.ViewGraphsPlotWindowMenu.Text = 'View Graphs Plot Window';
            app.ClearPlotsMenu = uimenu(app.PlotsMenu);
            app.ClearPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearPlotsMenuSelected, true);
            app.ClearPlotsMenu.Text = 'Clear Plots';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.RefreshMenu = uimenu(app.ViewMenu);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.RefreshMenu.Text = 'Refresh';
            app.LogWindowMenu = uimenu(app.ViewMenu);
            app.LogWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @LogWindowMenuSelected, true);
            app.LogWindowMenu.Text = 'Log Window';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.SNRCalculatorMenu = uimenu(app.ToolsMenu);
            app.SNRCalculatorMenu.Tooltip = {'Open SNR Calculator web application in browser window'};
            app.SNRCalculatorMenu.Text = 'SNR Calculator';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.ObservationPlannerHelpMenu = uimenu(app.HelpMenu);
            app.ObservationPlannerHelpMenu.MenuSelectedFcn = createCallbackFcn(app, @ObservationPlannerHelpMenuSelected, true);
            app.ObservationPlannerHelpMenu.Text = 'Observation Planner Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.PanelToolbar = uipanel(app.UIFigure);
            app.NewButton = uibutton(app.PanelToolbar, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Tooltip = {'Create new observation plan'};
            app.NewButton.Text = 'New';
            app.OpenButton = uibutton(app.PanelToolbar, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Open existing observation plan from database'};
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.PanelToolbar, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Save observation plan to database'};
            app.SaveButton.Text = 'Save';
            app.SubmitButton = uibutton(app.PanelToolbar, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Tooltip = {'Send plan to Mission Control for approval'};
            app.SubmitButton.Text = 'Submit';
            app.ValidateButton = uibutton(app.PanelToolbar, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Tooltip = {'Send plan for validation (may take up to 30 seconds)'};
            app.ValidateButton.Text = 'Validate';
            app.ParamsButton = uibutton(app.PanelToolbar, 'push');
            app.ParamsButton.ButtonPushedFcn = createCallbackFcn(app, @ParamsButtonPushed, true);
            app.ParamsButton.Tooltip = {'Show (and edit) observation plan parameters'};
            app.ParamsButton.Text = 'Params';
            app.LoginButton = uibutton(app.PanelToolbar, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Tooltip = {'Connect to server and login'};
            app.LoginButton.Text = 'Login';
            app.SNRCalcButton = uibutton(app.PanelToolbar, 'push');
            app.SNRCalcButton.ButtonPushedFcn = createCallbackFcn(app, @SNRCalcButtonPushed, true);
            app.SNRCalcButton.Tooltip = {'Open SNR Calculator web application in browser window'};
            app.SNRCalcButton.Text = 'SNR Calc';
            app.ConnectionStatusEditFieldLabel = uilabel(app.PanelToolbar);
            app.ConnectionStatusEditFieldLabel.Text = 'Connection Status';
            app.ConnectionStatusEditField = uieditfield(app.PanelToolbar, 'text');
            app.ConnectionStatusEditField.Editable = 'off';
            app.ConnectionStatusEditField.Tooltip = {'Server connection & login status'};
            app.ConnectionStatusEditField.Value = 'Backend Simulator';
            app.ModifiedLabel = uilabel(app.PanelToolbar);
            app.ModifiedLabel.Tooltip = {'Is current plan modified sience last saved?'};
            app.ModifiedLabel.Text = 'Modified';
            app.DuplicateButton = uibutton(app.PanelToolbar, 'push');
            app.DuplicateButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateButtonPushed, true);
            app.DuplicateButton.Tooltip = {'Duplicate current observation plan'};
            app.DuplicateButton.Text = 'Duplicate';
            app.RetractButton = uibutton(app.PanelToolbar, 'push');
            app.RetractButton.Enable = 'off';
            app.RetractButton.Visible = 'off';
            app.RetractButton.Tooltip = {'Send plan to Mission Control for approval'};
            app.RetractButton.Text = 'Retract !!!';
            app.GDriveCommentsButton = uibutton(app.PanelToolbar, 'push');
            app.GDriveCommentsButton.ButtonPushedFcn = createCallbackFcn(app, @GDriveCommentsButtonPushed, true);
            app.GDriveCommentsButton.Tooltip = {'Open SNR Calculator web application in browser window'};
            app.GDriveCommentsButton.Text = {'GDrive'; 'Comments'};
            app.HelpButton = uibutton(app.PanelToolbar, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Tooltip = {'Open SNR Calculator web application in browser window'};
            app.HelpButton.Text = 'Help';
            app.UniqueTargetsPanel = uipanel(app.UIFigure);
            app.UniqueTargetsPanel.Title = 'Unique Targets';
            app.UniqueTargetsPanel.SizeChangedFcn = createCallbackFcn(app, @UniqueTargetsPanelSizeChanged, true);
            app.UITableUniqueTargets = uitable(app.UniqueTargetsPanel);
            app.UITableUniqueTargets.ColumnName = '';
            app.UITableUniqueTargets.RowName = {};
            app.UITableUniqueTargets.ColumnEditable = true;
            app.UITableUniqueTargets.DoubleClickedFcn = createCallbackFcn(app, @UITableUniqueTargetsDoubleClicked, true);
            app.UITableUniqueTargets.ClickedFcn = createCallbackFcn(app, @UITableUniqueTargetsClicked, true);
            app.UITableUniqueTargets.SelectionChangedFcn = createCallbackFcn(app, @UITableUniqueTargetsSelectionChanged, true);
            app.Panel_6 = uipanel(app.UniqueTargetsPanel);
            app.AddUniqueTargetButton = uibutton(app.Panel_6, 'push');
            app.AddUniqueTargetButton.ButtonPushedFcn = createCallbackFcn(app, @AddUniqueTargetButtonPushed, true);
            app.AddUniqueTargetButton.Tooltip = {'Add new unique target'};
            app.AddUniqueTargetButton.Text = 'Add';
            app.EditUniqueTargetButton = uibutton(app.Panel_6, 'push');
            app.EditUniqueTargetButton.ButtonPushedFcn = createCallbackFcn(app, @EditUniqueTargetButtonPushed, true);
            app.EditUniqueTargetButton.Tooltip = {'Edit the selected unique target'};
            app.EditUniqueTargetButton.Text = 'Edit';
            app.LoadUniqueTargetsButton = uibutton(app.Panel_6, 'push');
            app.LoadUniqueTargetsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadUniqueTargetsButtonPushed, true);
            app.LoadUniqueTargetsButton.Tooltip = {'Load unique targets from text file'};
            app.LoadUniqueTargetsButton.Text = 'Load';
            app.EditPlanTargetButton_4 = uibutton(app.Panel_6, 'push');
            app.EditPlanTargetButton_4.ButtonPushedFcn = createCallbackFcn(app, @EditPlanTargetButton_4Pushed, true);
            app.EditPlanTargetButton_4.Tooltip = {'Edit the selected target'};
            app.EditPlanTargetButton_4.Text = 'Delete';
            app.EditPlanTargetButton_5 = uibutton(app.Panel_6, 'push');
            app.EditPlanTargetButton_5.ButtonPushedFcn = createCallbackFcn(app, @EditPlanTargetButton_5Pushed, true);
            app.EditPlanTargetButton_5.Tooltip = {'Edit the selected target'};
            app.EditPlanTargetButton_5.Text = 'Clear All';
            app.ShowUniqueTargetsWindowButton = uibutton(app.UniqueTargetsPanel, 'push');
            app.ShowUniqueTargetsWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowUniqueTargetsWindowButtonPushed, true);
            app.ShowUniqueTargetsWindowButton.Text = '...';
            app.HelpUniqueTargetsWindowButton = uibutton(app.UniqueTargetsPanel, 'push');
            app.HelpUniqueTargetsWindowButton.ButtonPushedFcn = createCallbackFcn(app, @HelpUniqueTargetsWindowButtonPushed, true);
            app.HelpUniqueTargetsWindowButton.Text = '?';
            app.PlanPanel = uipanel(app.UIFigure);
            app.PlanPanel.Title = 'Plan';
            app.PlanPanel.SizeChangedFcn = createCallbackFcn(app, @PlanPanelSizeChanged, true);
            app.UITablePlanTargets = uitable(app.PlanPanel);
            app.UITablePlanTargets.ColumnName = '';
            app.UITablePlanTargets.RowName = {};
            app.UITablePlanTargets.DoubleClickedFcn = createCallbackFcn(app, @UITablePlanTargetsDoubleClicked, true);
            app.UITablePlanTargets.ClickedFcn = createCallbackFcn(app, @UITablePlanTargetsClicked, true);
            app.UITablePlanTargets.SelectionChangedFcn = createCallbackFcn(app, @UITablePlanTargetsSelectionChanged, true);
            app.Panel_4 = uipanel(app.PlanPanel);
            app.BuildButton = uibutton(app.Panel_4, 'push');
            app.BuildButton.ButtonPushedFcn = createCallbackFcn(app, @BuildButtonPushed, true);
            app.BuildButton.Tooltip = {'Build plan (depends on plan type)'};
            app.BuildButton.Text = 'Build';
            app.CheckPlanTargetsButton = uibutton(app.Panel_4, 'push');
            app.CheckPlanTargetsButton.ButtonPushedFcn = createCallbackFcn(app, @CheckPlanTargetsButtonPushed, true);
            app.CheckPlanTargetsButton.Tooltip = {'Perform plan consistency check'};
            app.CheckPlanTargetsButton.Text = 'Check';
            app.EditPlanTargetButton = uibutton(app.Panel_4, 'push');
            app.EditPlanTargetButton.ButtonPushedFcn = createCallbackFcn(app, @EditPlanTargetButtonPushed, true);
            app.EditPlanTargetButton.Tooltip = {'Edit the selected target'};
            app.EditPlanTargetButton.Text = 'Edit';
            app.EditPlanTargetButton_2 = uibutton(app.Panel_4, 'push');
            app.EditPlanTargetButton_2.ButtonPushedFcn = createCallbackFcn(app, @EditPlanTargetButton_2Pushed, true);
            app.EditPlanTargetButton_2.Tooltip = {'Edit the selected target'};
            app.EditPlanTargetButton_2.Text = 'Delete';
            app.EditPlanTargetButton_3 = uibutton(app.Panel_4, 'push');
            app.EditPlanTargetButton_3.ButtonPushedFcn = createCallbackFcn(app, @EditPlanTargetButton_3Pushed, true);
            app.EditPlanTargetButton_3.Tooltip = {'Edit the selected target'};
            app.EditPlanTargetButton_3.Text = 'Clear All';
            app.EditPlanTargetButton_6 = uibutton(app.Panel_4, 'push');
            app.EditPlanTargetButton_6.ButtonPushedFcn = createCallbackFcn(app, @EditPlanTargetButton_6Pushed, true);
            app.EditPlanTargetButton_6.Tooltip = {'Edit the selected target'};
            app.EditPlanTargetButton_6.Text = 'Group';
            app.ShowPlanRowsWindowButton = uibutton(app.PlanPanel, 'push');
            app.ShowPlanRowsWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPlanRowsWindowButtonPushed, true);
            app.ShowPlanRowsWindowButton.Text = '...';
            app.HelpUniqueTargetsWindowButton_2 = uibutton(app.PlanPanel, 'push');
            app.HelpUniqueTargetsWindowButton_2.ButtonPushedFcn = createCallbackFcn(app, @HelpUniqueTargetsWindowButton_2Pushed, true);
            app.HelpUniqueTargetsWindowButton_2.Text = '?';
            app.ApprovedTargetsPanel = uipanel(app.UIFigure);
            app.ApprovedTargetsPanel.Title = 'Approved Targets';
            app.ApprovedTargetsPanel.SizeChangedFcn = createCallbackFcn(app, @ApprovedTargetsPanelSizeChanged, true);
            app.UITableApprovedTargets = uitable(app.ApprovedTargetsPanel);
            app.UITableApprovedTargets.ColumnName = '';
            app.UITableApprovedTargets.RowName = {};
            app.UITableApprovedTargets.SelectionChangedFcn = createCallbackFcn(app, @UITableApprovedTargetsSelectionChanged, true);
            app.Panel_5 = uipanel(app.ApprovedTargetsPanel);
            app.RefreshApprovedTargetsButton = uibutton(app.Panel_5, 'push');
            app.RefreshApprovedTargetsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshApprovedTargetsButtonPushed, true);
            app.RefreshApprovedTargetsButton.Tooltip = {'Refeatch data from uplanner object'};
            app.RefreshApprovedTargetsButton.Text = 'Retreive';
            app.RefreshApprovedTargetsButton_3 = uibutton(app.Panel_5, 'push');
            app.RefreshApprovedTargetsButton_3.ButtonPushedFcn = createCallbackFcn(app, @RefreshApprovedTargetsButton_3Pushed, true);
            app.RefreshApprovedTargetsButton_3.Tooltip = {'Refeatch data from uplanner object'};
            app.RefreshApprovedTargetsButton_3.Text = 'Clear';
            app.ShowApprovedTargetsWindowButton = uibutton(app.ApprovedTargetsPanel, 'push');
            app.ShowApprovedTargetsWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowApprovedTargetsWindowButtonPushed, true);
            app.ShowApprovedTargetsWindowButton.Text = '...';
            app.HelpUniqueTargetsWindowButton_3 = uibutton(app.ApprovedTargetsPanel, 'push');
            app.HelpUniqueTargetsWindowButton_3.ButtonPushedFcn = createCallbackFcn(app, @HelpUniqueTargetsWindowButton_3Pushed, true);
            app.HelpUniqueTargetsWindowButton_3.Text = '?';
            app.PlotSkyMapCurrentlyshowsgeneralskymapPanel = uipanel(app.UIFigure);
            app.PlotSkyMapCurrentlyshowsgeneralskymapPanel.Title = 'Plot - Sky Map (Currently shows general skymap)';
            app.AxesSkymapPlot = uiaxes(app.PlotSkyMapCurrentlyshowsgeneralskymapPanel);
            title(app.AxesSkymapPlot, 'Title')
            xlabel(app.AxesSkymapPlot, 'X')
            ylabel(app.AxesSkymapPlot, 'Y')
            zlabel(app.AxesSkymapPlot, 'Z')
            app.AxesSkymapPlot.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.AxesSkymapPlot.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Panel_10 = uipanel(app.PlotSkyMapCurrentlyshowsgeneralskymapPanel);
            app.PlotFlagUniqueCheckBox = uicheckbox(app.Panel_10);
            app.PlotFlagUniqueCheckBox.Text = {'Unique'; 'Targets'};
            app.PlotFlagUniqueCheckBox.Value = true;
            app.PlotFlagPlanCheckBox = uicheckbox(app.Panel_10);
            app.PlotFlagPlanCheckBox.Text = 'Plan';
            app.PlotFlagExtinctionCheckBox = uicheckbox(app.Panel_10);
            app.PlotFlagExtinctionCheckBox.Text = 'Extinction';
            app.PlotFlagExtinctionCheckBox.Value = true;
            app.PlotFlagCalibrationCheckBox = uicheckbox(app.Panel_10);
            app.PlotFlagCalibrationCheckBox.Text = {'Calibration'; 'Stars'};
            app.PlotFlagCalibrationCheckBox.Value = true;
            app.PlotFlagApprovedCheckBox = uicheckbox(app.Panel_10);
            app.PlotFlagApprovedCheckBox.Text = {'Approved'; 'Targets'};
            app.PlotFlagVisibleCheckBox = uicheckbox(app.Panel_10);
            app.PlotFlagVisibleCheckBox.Enable = 'off';
            app.PlotFlagVisibleCheckBox.Text = 'Visibility';
            app.UpdateSkyMapButton = uibutton(app.Panel_10, 'push');
            app.UpdateSkyMapButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSkyMapButtonPushed, true);
            app.UpdateSkyMapButton.Text = 'Plot!';
            app.cooSysLabel_4 = uilabel(app.Panel_10);
            app.cooSysLabel_4.Text = {'Coordinate'; 'System'};
            app.PlotCooSysDropDown = uidropdown(app.Panel_10);
            app.PlotCooSysDropDown.Items = {'Equatorial', 'Ecliptic', 'Galactic'};
            app.PlotCooSysDropDown.Value = 'Equatorial';
            app.OpenSkyMapPlotWindowButton = uibutton(app.PlotSkyMapCurrentlyshowsgeneralskymapPanel, 'push');
            app.OpenSkyMapPlotWindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSkyMapPlotWindowButtonPushed, true);
            app.OpenSkyMapPlotWindowButton.Text = '*';
            app.HelpSkyMapPlotWindowButton = uibutton(app.PlotSkyMapCurrentlyshowsgeneralskymapPanel, 'push');
            app.HelpSkyMapPlotWindowButton.ButtonPushedFcn = createCallbackFcn(app, @HelpSkyMapPlotWindowButtonPushed, true);
            app.HelpSkyMapPlotWindowButton.Text = '?';
            app.PlotGraphsDoubleClickUniqueTargetorPlanrowPanel = uipanel(app.UIFigure);
            app.PlotGraphsDoubleClickUniqueTargetorPlanrowPanel.Title = 'Plot - Graphs (Double Click Unique Target or Plan row)';
            app.AxesGraphsPlot = uiaxes(app.PlotGraphsDoubleClickUniqueTargetorPlanrowPanel);
            title(app.AxesGraphsPlot, 'Title')
            xlabel(app.AxesGraphsPlot, 'X')
            ylabel(app.AxesGraphsPlot, 'Y')
            zlabel(app.AxesGraphsPlot, 'Z')
            app.AxesGraphsPlot.ButtonDownFcn = createCallbackFcn(app, @AxesGraphsPlotButtonDown, true);
            app.cooSysLabel_2 = uilabel(app.PlotGraphsDoubleClickUniqueTargetorPlanrowPanel);
            app.cooSysLabel_2.Text = 'CalObj';
            app.PlotCalibObjDropDown = uidropdown(app.PlotGraphsDoubleClickUniqueTargetorPlanrowPanel);
            app.PlotCalibObjDropDown.Items = {};
            app.PlotCalibObjDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotCalibObjDropDownValueChanged, true);
            app.PlotCalibObjDropDown.Value = {};
            app.cooSysLabel_3 = uilabel(app.PlotGraphsDoubleClickUniqueTargetorPlanrowPanel);
            app.cooSysLabel_3.Text = 'Unique Target Name';
            app.GraphPlotUniqueTargetDropDown = uidropdown(app.PlotGraphsDoubleClickUniqueTargetorPlanrowPanel);
            app.GraphPlotUniqueTargetDropDown.Items = {};
            app.GraphPlotUniqueTargetDropDown.Value = {};
            app.ButtonGroup = uibuttongroup(app.PlotGraphsDoubleClickUniqueTargetorPlanrowPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.VisibilityButton = uiradiobutton(app.ButtonGroup);
            app.VisibilityButton.Text = 'Visibility';
            app.VisibilityButton.Value = true;
            app.CalibrationStarButton = uiradiobutton(app.ButtonGroup);
            app.CalibrationStarButton.Text = 'Calibration Star';
            app.OpenCalObjTableButton = uibutton(app.PlotGraphsDoubleClickUniqueTargetorPlanrowPanel, 'push');
            app.OpenCalObjTableButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCalObjTableButtonPushed, true);
            app.OpenCalObjTableButton.Text = 'CalObj';
            app.OpenGraphsPlotWindowButton = uibutton(app.PlotGraphsDoubleClickUniqueTargetorPlanrowPanel, 'push');
            app.OpenGraphsPlotWindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpenGraphsPlotWindowButtonPushed, true);
            app.OpenGraphsPlotWindowButton.Text = '*';
            app.HelpGraphsPlotWindowButton = uibutton(app.PlotGraphsDoubleClickUniqueTargetorPlanrowPanel, 'push');
            app.HelpGraphsPlotWindowButton.ButtonPushedFcn = createCallbackFcn(app, @HelpGraphsPlotWindowButtonPushed, true);
            app.HelpGraphsPlotWindowButton.Text = '?';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlanParamsTab = uitab(app.TabGroup);
            app.PlanParamsTab.Title = 'Plan Params';
            app.PlanTypeEditFieldLabel = uilabel(app.PlanParamsTab);
            app.PlanTypeEditFieldLabel.Text = 'Plan Type';
            app.PlanTypeEditField = uieditfield(app.PlanParamsTab, 'text');
            app.PlanTypeEditField.Editable = 'off';
            app.UserNameEditFieldLabel = uilabel(app.PlanParamsTab);
            app.UserNameEditFieldLabel.Text = 'User Name';
            app.UserNameEditField = uieditfield(app.PlanParamsTab, 'text');
            app.UserNameEditField.Editable = 'off';
            app.PlanTitleEditFieldLabel = uilabel(app.PlanParamsTab);
            app.PlanTitleEditFieldLabel.Text = 'Plan Title';
            app.PlanTitleEditField = uieditfield(app.PlanParamsTab, 'text');
            app.PlanTitleEditField.ValueChangedFcn = createCallbackFcn(app, @PlanTitleEditFieldValueChanged, true);
            app.StartTimeEditFieldLabel = uilabel(app.PlanParamsTab);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.PlanParamsTab, 'text');
            app.StartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditFieldValueChanged2, true);
            app.StartTimeEditField.Editable = 'off';
            app.StartTimeEditField.Tooltip = {'Enter plan start time (i.e. 2024-12-04 00:00:00)'};
            app.EndTimeEditFieldLabel = uilabel(app.PlanParamsTab);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.PlanParamsTab, 'text');
            app.EndTimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimeEditFieldValueChanged, true);
            app.EndTimeEditField.Editable = 'off';
            app.HelpPlanParamsButton = uibutton(app.PlanParamsTab, 'push');
            app.HelpPlanParamsButton.ButtonPushedFcn = createCallbackFcn(app, @HelpPlanParamsButtonPushed, true);
            app.HelpPlanParamsButton.Text = '?';
            app.Panel_8 = uipanel(app.UIFigure);
            app.StatusTextAreaLabel = uilabel(app.Panel_8);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.Panel_8);
            app.StatusTextArea.Editable = 'off';
            app.RefreshApprovedTargetsButton_2 = uibutton(app.Panel_8, 'push');
            app.RefreshApprovedTargetsButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshApprovedTargetsButton_2Pushed, true);
            app.RefreshApprovedTargetsButton_2.Text = 'Clear';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.StatusTab = uitab(app.TabGroup2);
            app.StatusTab.Title = 'Status';
            app.BuildEditFieldLabel = uilabel(app.StatusTab);
            app.BuildEditFieldLabel.Text = 'Build';
            app.BuildTimeEditField = uieditfield(app.StatusTab, 'text');
            app.BuildTimeEditField.Editable = 'off';
            app.ValidationEditFieldLabel = uilabel(app.StatusTab);
            app.ValidationEditFieldLabel.Text = 'Validation';
            app.ValidationTimeEditField = uieditfield(app.StatusTab, 'text');
            app.ValidationTimeEditField.Editable = 'off';
            app.SubmitEditFieldLabel = uilabel(app.StatusTab);
            app.SubmitEditFieldLabel.Text = 'Submit';
            app.SubmitTimeEditField = uieditfield(app.StatusTab, 'text');
            app.SubmitTimeEditField.Editable = 'off';
            app.BuildShortStatusEditField = uieditfield(app.StatusTab, 'text');
            app.BuildShortStatusEditField.Editable = 'off';
            app.ValidationShortStatusEditField = uieditfield(app.StatusTab, 'text');
            app.ValidationShortStatusEditField.Editable = 'off';
            app.SubmitShortStatusEditField = uieditfield(app.StatusTab, 'text');
            app.SubmitShortStatusEditField.Editable = 'off';
            app.BuildStatusButton = uibutton(app.StatusTab, 'push');
            app.BuildStatusButton.ButtonPushedFcn = createCallbackFcn(app, @BuildStatusButtonPushed, true);
            app.BuildStatusButton.Tooltip = {'Load unique targets from text file'};
            app.BuildStatusButton.Text = '...';
            app.ValidationStatusButton = uibutton(app.StatusTab, 'push');
            app.ValidationStatusButton.ButtonPushedFcn = createCallbackFcn(app, @ValidationStatusButtonPushed, true);
            app.ValidationStatusButton.Tooltip = {'Load unique targets from text file'};
            app.ValidationStatusButton.Text = '...';
            app.SubmitStatusButton = uibutton(app.StatusTab, 'push');
            app.SubmitStatusButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitStatusButtonPushed, true);
            app.SubmitStatusButton.Tooltip = {'Load unique targets from text file'};
            app.SubmitStatusButton.Text = '...';
            app.HelpStatusInfoButton = uibutton(app.StatusTab, 'push');
            app.HelpStatusInfoButton.ButtonPushedFcn = createCallbackFcn(app, @HelpStatusInfoButtonPushed, true);
            app.HelpStatusInfoButton.Text = '?';
            app.PanelTopHeader = uipanel(app.UIFigure);
            app.LabelTopStatus = uilabel(app.PanelTopHeader);
            app.LabelTopStatus.Visible = 'off';
            app.LabelTopStatus.Text = 'The plan was submitted and cannot be modified.';
            app.LabelTopNamespace = uilabel(app.PanelTopHeader);
            app.LabelTopNamespace.Text = 'OPER';
            app.LabelTopUser = uilabel(app.PanelTopHeader);
            app.LabelTopUser.Text = 'Please login';
            app.LabelTopTime = uilabel(app.PanelTopHeader);
            app.LabelTopTime.Text = 'UTC: 2025-01-01 00:00:00';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlannerMain(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end